classdef Adddata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Add                     matlab.ui.Figure
        Label                   matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        ModulepositionSpinner   matlab.ui.control.Spinner
        AddButton               matlab.ui.control.Button
        NameEditFieldLabel      matlab.ui.control.Label
        NameEditField           matlab.ui.control.EditField
        CloseButton             matlab.ui.control.Button
        SequenceLabel           matlab.ui.control.Label
        ModulepositionLabel     matlab.ui.control.Label
        CheckBox                matlab.ui.control.CheckBox
        SingleintegrationLabel  matlab.ui.control.Label
        CheckBox_2              matlab.ui.control.CheckBox
        Fusedtomodule4Label     matlab.ui.control.Label
        OptionsButton           matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
        left_sites={'ATCC' 'TATG' 'GAAC' 'CGCA' 'CAGG' 'AGCA'};
        right_sites={'CATA' 'GTTC' 'TGCG' 'CCTG' 'TGCT' 'ATGG'};
        left_puc='CGAATGCATCTAGATATCGGATCCCGGTCTCG';
        right_puc='GCAGGCCTCTGCAGTCGACGGGCCCGGTCTCT';
        bsaI={'GGTCTC' 'GAGACC'};
        donnes;
        dbg;
        dpathg;
        mainapbg;
        linkerg;
        site='GGCGCGCCGGCCGGCC';
    end

    methods (Access = private)
    
         function results = bsaIfind(app,seq)
            l=length(seq);
            out1={[],[]};
           if l>5
           for n=[1 2]   
           for i=1:l-5
               k=1;
               while (k<=6)&&(seq(i+k-1)==app.bsaI{n}(k))
                  k=k+1;
               end
               if k==7
                  if n==1
                  out1{1}=[out1{1} i];
                  out1{2}=[out1{2} n];
                  else
                  out1{1}=[out1{1} i];
                  out1{2}=[out1{2} n];
                  end
               end
           end
           end
           end
           results=out1;
        end
        
        function resul = seqcomp(app,seq1)
            for i=1:length(seq1)
                if seq1(i)=='A'
                     seq1(i) = 'T';
                else
                    if seq1(i)=='G'
                         seq1(i)='C';
                    else
                        if seq1(i)=='C'
                             seq1(i)='G';
                        else
                             seq1(i)='A';
                        end
                    end
                end
            end
            resul=seq1;
        end
        
        function resul1 = seqok(app,seq)
            if ~isempty(seq)
                ok =1;
                seq =upper(seq) ;
                for i = 1:length(seq)
                   if (seq(i)~='A')&&(seq(i)~='T')&&(seq(i)~='C')&&(seq(i)~='G')
                       ok = 0;
                   end
                end
                resul1 = ok ; 
            else
                resul1 = 0;
            end
        end
        
        function [resulg,seq1] = modulok(app,seq,gmg)
            if seqok(app,seq)
                ogk = 1;
                   seq = upper(seq)  ;
                   seq2=seq;
                if length(seq)>5
                   bsawwg = bsaIfind(app,seq);
                   if length(bsawwg{1})>0
                       [ogk, seq2]=mgok(app,seq,gmg) ;
                   else
                   x1g=app.left_sites{gmg};
                   x2g=app.right_sites{gmg};
                   x2g=fliplr(x2g);
                   x2g=seqcomp(app,x2g);
                   mg=['GTCTCG' x1g seq x2g 'AGAGAC'];
                   bsaw1=bsaIfind(app,mg);
                   if length(bsaw1{1})>0
                       ogk = 0 ;
                       seq2 = [];
                   end
                   end
                   else
                   x1g=app.left_sites{gmg};
                   x2g=app.right_sites{gmg};
                   x2g=fliplr(x2g);
                   x2g=seqcomp(app,x2g);
                   mg=['GTCTCG' x1g seq x2g 'AGAGAC'];
                   bsaw1=bsaIfind(app,mg);
                   if length(bsaw1{1})>0
                       ogk = 0 ;
                       seq2 = [];
                   end
                end
              seq1=seq2;
                                                                resulg =ogk ;
            else
                seq1=[];
                resulg = 0;
            end
        end
        function [resulg, seq1] = mgok(app,seq,placement)
                ogk = 0;
                seqg = [];
                if length(seq)>22
                  seq = upper(seq)  ;
                   wgng = length(seq) ;
                   seq =[seq seq(1:5) ] ;
                   bsawwg1 = strfind(seq,app.bsaI{2});
                   bsawwg2 = strfind(seq,app.bsaI{1});
                   x1g=app.left_sites{placement};
                   x2g=app.right_sites{placement};
                   y1g=fliplr(x2g);
                   y1g=seqcomp(app,y1g);
                   y2g=fliplr(x1g);
                   y2g=seqcomp(app,y2g);
                   if (length(bsawwg1)==1)&&(length(bsawwg2)==1)
                       seq = seq(1:end-5);
                       y1 = bsawwg1-5;
                       y2 = bsawwg1 + 5;
                       if y2>wgng
                           y2 = y2-wgng;
                       end
                       if y1<1
                           y1 = y1+wgng;
                       end
                       x1 = bsawwg2;
                       x2 = bsawwg2 + 10;
                       if x2>wgng
                           x2 = x2-wgng;
                       end
                       if ((x1<x2)&&(y1<y2)&&((x2<y1)||(y2<x1)))||((((x1>x2)&&(y1<y2))||((x1<x2)&&(y1>y2)))&&(x2<y1)&&(y2<x1))
                       if x1>y2
                           wgg = y2+round((x1-y2-1)/2) ;
                           seq =[seq(wgg+1:end) seq(1:wgg)];
                           x1 =x1-wgg;
                           y2=wgng+y2-wgg;
                           if y1>wgg y1=y1-wgg;
                           else
                           y1=wgng+y1-wgg;
                           end
                           if x2>wgg x2=x2-wgg;
                           else
                           x2=wgng+x2-wgg;
                           end
                       end
                       if (strcmp(seq(x1+7:x1+10),x1g )&&strcmp(seq(y1:y1+3),y1g))||(strcmp(seq(x1+7:x1+10),x2g )&&strcmp(seq(y1:y1+3),y2g))
                       ogk=1;
                       if (strcmp(seq(x1+7:x1+10),x2g )&&strcmp(seq(y1:y1+3),y2g))
                           seq=fliplr(seq);
                           seq=seqcomp(app,seq);
%                            left_sites={'ATCC' 'TATG' 'GAAC' 'CGCA' 'CAGG' 'AGCA'};
%                            right_sites={'CATA' 'GTTC' 'TGCG' 'CCTG' 'TGCT' 'ATGG'};
                       end
                           seqg=seq;
                           if y1-x2<2
                               ogk=0;
                               seqg=[];
                           end
                       end
                       end
                   end
                end
                seq1 =  seqg;
                                                                resulg =ogk ;
         end
        function [resulg,seq1] = cpok(app,seq)
            if seqok(app,seq)
                ogk = 0;
                seqg = [];
                if length(seq)>50
                   seq = upper(seq)  ;
                   wgng = length(seq) ;
                   seq2 = fliplr(seq);
                   seq2 = seqcomp( app,seq2 ) ;
                   seq =[seq seq(1:24)] ;
                   seq2 =[seq2 seq2(1:24)] ;
                   wwg1 = app.left_puc(1:25);
                   wgg1 = app.right_puc(1:25) ;
                   wgg1 = fliplr(wgg1) ;
                   wgg1 = seqcomp(app,wgg1) ;
                   rgg1 =  strfind(seq,wgg1 ) ;
                   lgg1 =  strfind(seq,wwg1 ) ;
                   rgg2 =  strfind(seq2,wgg1 ) ;
                   lgg2 =  strfind(seq2,wwg1 ) ;
                   if ((length(rgg1)==1)&&(length(lgg1)==1)&&(length(rgg2)==0)&&(length(lgg2)==0))||((length(rgg1)==0)&&(length(lgg1)==0)&&(length(rgg2)==1)&&(length(lgg2)==1))
                       ogk = 1 ;
                       if length(rgg1)==1
                           if (rgg1+25-lgg1>=wgng)||((rgg1+25-lgg1<=25)&&(rgg1+25-lgg1>=0))
                               ogk =0 ;
                           else
                               seq = seq(1:end-24) ;
                               a = lgg1;
                               b=rgg1+24;
                               if b>wgng
                                   b=b-wgng;
                               end
                               if a>b
                               seq=[seq(b+round((a-b-1)/2)+1:end) seq(1:b+round((a-b-1)/2)) ];  
                               end
                               seqg = seq ;
                           end
                       else
                       if (rgg2+25-lgg2>=wgng)||((rgg2+25-lgg2<=25)&&(rgg2+25-lgg2>=0))
                               ogk =0 ;
                               else
                               seq2 = seq2(1:end-24) ;
                               a = lgg2;
                               b=rgg2+24;
                               if b>wgng
                                   b=b-wgng;
                               end
                               if a>b
                               seq2=[seq2(b+round((a-b-1)/2)+1:end) seq2(1:b+round((a-b-1)/2)) ];  
                               end
                               seqg = seq2 ;
                       end
                       end
                   end
                end
                                                                resulg =ogk ;
                                                                seq1 = seqg       ;
            else
                resulg = 0;
                seq1 = [];
            end
        end
        
         function [resulg, seq1] = gapok(app,seq)
            if seqok(app,seq)
                ogk = 0;
                seqg = [];
                if length(seq)>22
                  seq = upper(seq)  ;
                   wgng = length(seq) ;
                   seq =[seq seq(1:5) ] ;
                   bsawwg1 = strfind(seq,app.bsaI{2});
                   bsawwg2 = strfind(seq,app.bsaI{1});
                   if (length(bsawwg1)==1)&&(length(bsawwg2)==1)
                       seq = seq(1:end-5);
                       x1 = bsawwg1-5;
                       x2 = bsawwg1 + 5;
                       if x2>wgng
                           x2 = x2-wgng;
                       end
                       if x1<1
                           x1 = x1+wgng;
                       end
                       y1 = bsawwg2;
                       y2 = bsawwg2 + 10;
                       if y2>wgng
                           y2 = y2-wgng;
                       end
                       if ((x1<x2)&&(y1<y2)&&((x2<y1)||(y2<x1)))||((((x1>x2)&&(y1<y2))||((x1<x2)&&(y1>y2)))&&(x2<y1)&&(y2<x1))
                       if x1>y2
                           wgg = y2+round((x1-y2-1)/2) ;
                           seq =[seq(wgg+1:end) seq(1:wgg)];
                           x1 =x1-wgg;
                           y2=wgng+y2-wgg;
                           if y1>wgg y1=y1-wgg;
                           else
                           y1=wgng+y1-wgg;
                           end
                           if x2>wgg x2=x2-wgg;
                           else
                           x2=wgng+x2-wgg;
                           end
                       end
                       if (strcmp(seq(x1:x1+3),'ATCC' )&&strcmp(seq(y1+7:y1+10),'CCAT'))||(strcmp(seq(x1:x1+3),'ATGG' )&&strcmp(seq(y1+7:y1+10),'GGAT'))
                       ogk=1;
                       if (strcmp(seq(x1:x1+3),'ATGG' )&&strcmp(seq(y1+7:y1+10),'GGAT'))
                           seq=fliplr(seq);
                           seq=seqcomp(app,seq);
                       end
                           seqg=seq;
                           if y1-x2<2
ogk=0;
 seqg=[];
                           end
                       end
                       end
                   end
                end
                seq1 =  seqg;
                                                                resulg =ogk ;
            else
                seq1 = [];
                resulg = 0;
            end
         end
         function [resul,gg] = nameok(app,seq)
             if ~isempty(seq)
                 if contains(seq,' ')||contains(seq,'@')||contains(seq,'.')
                     gg=[] ;
                     resul = 0;
                 else
                     gg=seq ;
                     resul = 1;
                 end
                     
             else
                 gg=[];
                 resul=0 ;
             end
         end
        
        
    end

    methods (Access = public)
    
    function sitset(app,sequence)
           app.site=sequence; 
        end
        
        function resebutton(app)
            app.OptionsButton.Enable='on';
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, db, names, path)
            app.mainapbg=main;
            if strcmp(db,'Modules')
            app.CheckBox.Visible = 'on';
            app.SingleintegrationLabel.Visible = 'on';
               app.ModulepositionSpinner.Visible='on' ;
               app.ModulepositionLabel.Visible='on' ;
               app.OptionsButton.Visible='on' ;
               app.Label.Text='Add new module to the COSPLAY data base';
               app.SequenceLabel.Text='Sequence (module sequence alone or entire sequence of the plasmid carrying the module):';
           else
               app.ModulepositionSpinner.Visible='off' ;
               app.ModulepositionLabel.Visible='off' ;
               app.SequenceLabel.Text='Sequence:';
                if strcmp(db,'Carrying vectors')
                     app.Label.Text='Add new carrying plasmid to the COSPLAY data base';
                else
                     app.Label.Text='Add new destination vector to the COSPLAY data base';
                end
           end
           app.donnes=names;
           app.dpathg=path;
           app.dbg=db;
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            m_homol = [value{:}];
            m_homol = upper(m_homol);
            m_homol = strrep(m_homol,' ','');
            m = length(m_homol(m_homol=='A'));
            m = m+length(m_homol(m_homol=='T'));
            m = m+length(m_homol(m_homol=='C'));
            m = m+length(m_homol(m_homol=='G'));
            if  1>length(m_homol)
                app.TextArea.BackgroundColor = [1 0.8 0.8] ;
            else
                if m<length(m_homol)
                app.TextArea.BackgroundColor = [1 0.8 0.8] ;
                else
                app.TextArea.BackgroundColor = [0.8 1 0.8 ] ;
                end
            end
        end

        % Close request function: Add
        function AddCloseRequest(app, event)
            editdg(app.mainapbg);
            delete(app.linkerg);
            delete(app);
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            while (~isempty(value))&&(value(end)==' ')
                value=value(1:end-1);
            end
            if  isempty(value)
                app.NameEditField.BackgroundColor = [1 0.8 0.8] ;
            else
                if contains(value,' ')||contains(value,'@')||contains(value,'.')
                app.NameEditField.BackgroundColor = [1 0.8 0.8] ;
                else
                app.NameEditField.BackgroundColor = [0.8 1 0.8 ] ;
                end
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
                 delete(app.linkerg);
                 editdg(app.mainapbg);
                 delete(app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
value1 = app.NameEditField.Value;
        moduleplasmi=0;
            while (~isempty(value1))&&(value1(end)==' ')
                value1=value1(1:end-1);
            end
            if  isempty(value1)||contains(value1,' ')||contains(value1,'@')||contains(value1,'.')
                app.NameEditField.BackgroundColor = [1 0.8 0.8] ;
                                                        uialert(app.Add,    'Name should contain at least one character (white spaces, points and "@" character are forbiden) !', 'Wrong name');
                        return
            end
            switch app.dbg
                case 'Modules'
                    position=app.ModulepositionSpinner.Value;
                    if ~isempty(app.donnes.modulename{position})
                        for i=1:length(app.donnes.modulename{position})
                            if strcmp(app.donnes.modulename{position}{i},value1)
                                uialert(app.Add,    ['There is already a module ' num2str(position) ' named ' value1 ' !'], 'Wrong name') ;
                                return
                            end
                        end
                    end
                case 'Carrying vectors'
                    if ~isempty(app.donnes.cpname)
                        for i=1:length(app.donnes.cpname)
                            if strcmp(app.donnes.cpname{i},value1)
                                uialert(app.Add,    ['There is already a carrying vector named ' value1 ' !'], 'Wrong name') ;
                                return
                            end
                        end
                    end
                case 'Destination vectors'
                     if ~isempty(app.donnes.apname)
                        for i=1:length(app.donnes.apname)
                            if strcmp(app.donnes.apname{i},value1)
                                uialert(app.Add,    ['There is already a destination vector named ' value1 ' !'], 'Wrong name') ;
                                return
                            end
                        end
                    end
            end
            value = app.TextArea.Value;
            m_homol = [value{:}];
            m_homol = upper(m_homol);
            m_homol = strrep(m_homol,' ','');
            m = length(m_homol(m_homol=='A'));
            m = m+length(m_homol(m_homol=='T'));
            m = m+length(m_homol(m_homol=='C'));
            m = m+length(m_homol(m_homol=='G'));
            single = app.CheckBox.Value;
             m_pos = app.ModulepositionSpinner.Value;
             if strcmp(app.dbg,'Modules')&&(m_pos==1)&&(single==1)&&(80>length(m_homol)||m<length(m_homol))
                 qwe= sprintf('A/a T/t G/g C/c code required !\nMinimum 80 nt for single integration!');
                  uialert(app.Add,    qwe, 'Wrong code');
                        return
             end    
            if  1>length(m_homol)||m<length(m_homol)
                app.TextArea.BackgroundColor = [1 0.8 0.8] ;
                qwe= sprintf('A/a T/t G/g C/c code required !\nMinimum 1 nt !');
                           uialert(app.Add,    qwe, 'Wrong code');
                        return
            end
            switch app.dbg
                case 'Modules'
           [okg sequence]=modulok(app,m_homol,m_pos);
           if okg==0
               rqw = app.right_sites{m_pos};
               rqw = fliplr(rqw);
                rqw = seqcomp(app,rqw);
               uialert(app.Add,[    'The module sequence is incorrect ! Check that it is either linear with no bsaI sites or circular (plasmid) containing 2 bsaI sites as well as ' app.left_sites{m_pos} ' and ' rqw ' linker sequences !'], 'Wrong sequence');
           return
           end
           bsasites = bsaIfind(app,sequence);
           if length(bsasites{1})==2
               moduleplasmi=1;
               type=['module' num2str(m_pos)];
               namecall=value1;
               seqcall=sequence;
               app.donnes.modulename{m_pos}=[app.donnes.modulename{m_pos} value1];
               app.donnes.moduleseq{m_pos}=[app.donnes.moduleseq{m_pos} sequence];
            else
                                         %======================
            stopcodon = app.CheckBox_2.Value;
if (m_pos==1)&&(single==1)
 if strcmp(app.site,'GGCGCGCCGGCCGGCC')
        r_taile = [app.right_puc app.right_sites{m_pos}];
        r_taile = fliplr(r_taile);
        r_taile = seqcomp(app,r_taile);
 recombh = [app.left_puc app.left_sites{m_pos} m_homol(end-39:end) app.site m_homol(1:40) r_taile];
        if (length(strfind(recombh,'GGCGCGCC'))>1)
            if (length(strfind(recombh,'GGCCGGCC'))>1)
        qew12 = sprintf('The final inverted module contains additional restriction sites for both AscI and FseI enzymes ! You have to use another restriction enzyme to cut the single interation plasmid !\nOpen the Options menu and enter another restriction enzyme site in the single integration parameters !');
        uialert(app.Add,  qew12 , 'Bad linker');
           return
            end
        end
    else
   r_taile = [app.right_puc app.right_sites{m_pos}];
        r_taile = fliplr(r_taile);
        r_taile = seqcomp(app,r_taile);
 recombh = [app.left_puc app.left_sites{m_pos} m_homol(end-39:end) app.site m_homol(1:40) r_taile];
       if (length(strfind(recombh,app.site))>1)
        qew12 = sprintf('The final inverted module contains additional restriction sites for the enzyme that you have choosed ! You have to use another restriction enzyme to cut the single interation plasmid !\nOpen the Options menu and enter another restriction enzyme site in the single integration parameters !');
        uialert(app.Add,  qew12 , 'Bad linker');
      return
        end
    end
    bwwwgg = bsaIfind(app,recombh);
    if length(bwwwgg{1})>2
    uialert(app.Add,  'The final inverted module contains at least one BsaI internal site. The final module is generated by inverting the first and last 40 bp of the initial module. Please alter these sequences in order to eliminate the additional BsaI sites !' , 'BsaI internal sites');
  return
    end
    seq_data_base = recombh(37:end-36);
    type=['module' num2str(m_pos)];
    namecall=value1;
    seqcall=seq_data_base;
    app.donnes.modulename{m_pos}=[app.donnes.modulename{m_pos} value1];
    app.donnes.moduleseq{m_pos}=[app.donnes.moduleseq{m_pos} seq_data_base];
else
           if (m_pos==3)&&(stopcodon==1)
         if length(m_homol)>2
           r_m = m_homol(end-2:end);
           if strcmp(r_m,'TGA')||strcmp(r_m,'TAA')||strcmp(r_m,'TAG')
                if length(m_homol)>3
               qwe1= sprintf('The module sequence contains a STOP codon at the end !\nDo you want to remove it ?');
              selection1 = uiconfirm(app.Add,qwe1,'Select action',...
                        'Icon','warning','Options',{'Remove STOP','Keep STOP','Cancel'});
                    if strcmp(selection1,'Remove STOP')
                    m_homol = m_homol(1:end-3);
                    else
                        if strcmp(selection1,'Cancel')
             return
                        end
                    end
                else
           qwe1= sprintf('The module sequence contains a STOP codon at the end ! However the STOP codon can not be removed because the module sequence is already too short !\nDo you want to continue ?');
              selection1 = uiconfirm(app.Add,qwe1,'Select action',...
                        'Icon','warning','Options',{'Keep STOP','Cancel'});
                    if strcmp(selection1,'Cancel')
           return
                    end 
                end
           end
         end
           seq_data_base = [m_homol 'GGAGG'];
           type=['module' num2str(m_pos)];
           namecall=value1;
           seqcall=seq_data_base;
           app.donnes.modulename{m_pos}=[app.donnes.modulename{m_pos} value1];
           app.donnes.moduleseq{m_pos}=[app.donnes.moduleseq{m_pos} seq_data_base];
           else
           seq_data_base = m_homol;
           type=['module' num2str(m_pos)];
           namecall=value1;
           seqcall=seq_data_base;
           app.donnes.modulename{m_pos}=[app.donnes.modulename{m_pos} value1];
           app.donnes.moduleseq{m_pos}=[app.donnes.moduleseq{m_pos} seq_data_base];
           end
end
                                        %======================
           end
                case 'Carrying vectors'
[okg sequence]=cpok(app,m_homol);
           if okg==0
               rqw = app.right_puc;
               rqw = fliplr(rqw);
                rqw = seqcomp(app,rqw);
               uialert(app.Add,[    'The carrying vector sequence is incorrect ! Check that it contains the PUC57 homologous sequences: [' app.left_puc '] and [' rqw '] correctly positioned in order to integrate a module between them !'], 'Wrong sequence');
           return
           end
           seq_data_base = sequence;
           type='cp';
           namecall=value1;
           seqcall=seq_data_base;
           app.donnes.cpname=[app.donnes.cpname value1];
          app.donnes.cpseq=[app.donnes.cpseq seq_data_base];
                case 'Destination vectors'
                    [okg sequence]=gapok(app,m_homol);
           if okg==0
                rqw = app.right_sites{6};
               rqw = fliplr(rqw);
                rqw = seqcomp(app,rqw);
               uialert(app.Add,[    'The destination vector sequence is incorrect ! Check that it contains 2 bsaI sites as well as ' app.left_sites{1} ' and ' rqw ' linker sequences !'], 'Wrong sequence');
           return
           end
           seq_data_base = sequence;
           type='ap';
           namecall=value1;
           seqcall=seq_data_base;
           app.donnes.apname=[app.donnes.apname value1];
          app.donnes.apseq=[app.donnes.apseq seq_data_base];
            end
            app.NameEditField.Value='';
            app.NameEditField.BackgroundColor=[1 1 1];
            app.TextArea.Value='';
            app.TextArea.BackgroundColor=[1 1 1];
            adddatab(app.mainapbg,type,namecall,seqcall,app.dpathg);
         if moduleplasmi
            uialert(app.Add, 'Information added to the data base ! Note that the module sequence already includes a carrying plasmid bone and thus it has been entirely copied to the data base without any further modifications if selected (single integration rearrangement or module 3 to 4 fusion) !', 'Success', 'Icon' , 'success');
            else
            uialert(app.Add, 'Information added to the data base !', 'Success', 'Icon' , 'success');
            end
        end

        % Value changing function: ModulepositionSpinner
        function ModulepositionSpinnerValueChanging(app, event)
            Value = event.Value;
            if Value>1
            app.CheckBox.Visible = 'off'; 
            app.SingleintegrationLabel.Visible = 'off';
            app.OptionsButton.Visible='off' ;
            else
            app.CheckBox.Visible = 'on';
            app.SingleintegrationLabel.Visible = 'on';
            app.OptionsButton.Visible='on' ;
            end
            if Value==3
            app.CheckBox_2.Visible = 'on'; 
            app.Fusedtomodule4Label.Visible = 'on';
            else
            app.CheckBox_2.Visible = 'off'; 
            app.Fusedtomodule4Label.Visible = 'off';
            end
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
           app.OptionsButton.Enable='off';
           delete(app.linkerg);
           app.linkerg=optionsingle(app,app.site) ;
           linkseqchane(app.mainapbg, app.linkerg);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Add = uifigure;
            app.Add.Name = 'Add to data base';
            app.Add.CloseRequestFcn = createCallbackFcn(app, @AddCloseRequest, true);
            app.Label = uilabel(app.Add);
            app.TextArea = uitextarea(app.Add);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.ModulepositionSpinner = uispinner(app.Add);
            app.ModulepositionSpinner.ValueChangingFcn = createCallbackFcn(app, @ModulepositionSpinnerValueChanging, true);
            app.ModulepositionSpinner.Limits = [1 6];
            app.ModulepositionSpinner.Visible = 'off';
            app.ModulepositionSpinner.Value = 1;
            app.AddButton = uibutton(app.Add, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add ✓';
            app.NameEditFieldLabel = uilabel(app.Add);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.Add, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.CloseButton = uibutton(app.Add, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close ✗';
            app.SequenceLabel = uilabel(app.Add);
            app.SequenceLabel.Text = 'Sequence:';
            app.ModulepositionLabel = uilabel(app.Add);
            app.ModulepositionLabel.Visible = 'off';
            app.ModulepositionLabel.Text = 'Module position';
            app.CheckBox = uicheckbox(app.Add);
            app.CheckBox.Visible = 'off';
            app.CheckBox.Text = '';
            app.SingleintegrationLabel = uilabel(app.Add);
            app.SingleintegrationLabel.Visible = 'off';
            app.SingleintegrationLabel.Text = 'Single integration';
            app.CheckBox_2 = uicheckbox(app.Add);
            app.CheckBox_2.Visible = 'off';
            app.CheckBox_2.Text = '';
            app.Fusedtomodule4Label = uilabel(app.Add);
            app.Fusedtomodule4Label.Visible = 'off';
            app.Fusedtomodule4Label.Text = 'Fused to module 4';
            app.OptionsButton = uibutton(app.Add, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Visible = 'off';
            app.OptionsButton.Text = 'Options';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Adddata(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Add)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Add)
        end
    end
end