classdef Sequencedata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Sequencedit            matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        ValidatechangesButton  matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        UndochangesButton      matlab.ui.control.Button
        ExportsequenceButton   matlab.ui.control.Button
        ExtractmodulesequenceonlyButton  matlab.ui.control.Button
        InsertintoacarryingplasmidButton  matlab.ui.control.Button
        Panel2                 matlab.ui.container.Panel
        ListBox                matlab.ui.control.ListBox
        ModulepositionSpinner  matlab.ui.control.Spinner
        ModulepositionLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        Property % Description
        left_sites={'ATCC' 'TATG' 'GAAC' 'CGCA' 'CAGG' 'AGCA'};
        right_sites={'CATA' 'GTTC' 'TGCG' 'CCTG' 'TGCT' 'ATGG'};
        left_puc='CGAATGCATCTAGATATCGGATCCCGGTCTCG';
        right_puc='GCAGGCCTCTGCAGTCGACGGGCCCGGTCTCT';
        bsaI={'GGTCTC' 'GAGACC'};
        donnesnames;
        donneseq;
        donnes;
        dbg;
        dpathg;
        mainapbg;
        donnesfirst;
        inserw;
        inseropenegg=0;
        featureb={};
    end

    methods (Access = private)
    
         function results = bsaIfind(app,seq)
            l=length(seq);
            out1={[],[]};
           if l>5
           for n=[1 2]   
           for i=1:l-5
               k=1;
               while (k<=6)&&(seq(i+k-1)==app.bsaI{n}(k))
                  k=k+1;
               end
               if k==7
                  if n==1
                  out1{1}=[out1{1} i];
                  out1{2}=[out1{2} n];
                  else
                  out1{1}=[out1{1} i];
                  out1{2}=[out1{2} n];
                  end
               end
           end
           end
           end
           results=out1;
        end
        
        function resul = seqcomp(app,seq1)
            for i=1:length(seq1)
                if seq1(i)=='A'
                     seq1(i) = 'T';
                else
                    if seq1(i)=='G'
                         seq1(i)='C';
                    else
                        if seq1(i)=='C'
                             seq1(i)='G';
                        else
                             seq1(i)='A';
                        end
                    end
                end
            end
            resul=seq1;
        end
        
        function resul1 = seqok(app,seq)
            if ~isempty(seq)
                ok =1;
                seq =upper(seq) ;
                for i = 1:length(seq)
                   if (seq(i)~='A')&&(seq(i)~='T')&&(seq(i)~='C')&&(seq(i)~='G')
                       ok = 0;
                   end
                end
                resul1 = ok ; 
            else
                resul1 = 0;
            end
        end
        
        function [resulg,seq1] = modulok(app,seq,gmg)
            if seqok(app,seq)
                ogk = 1;
                   seq = upper(seq)  ;
                   seq2=seq;
                if length(seq)>5
                   bsawwg = bsaIfind(app,seq);
                   if length(bsawwg{1})>0
                       [ogk, seq2]=mgok(app,seq,gmg) ;
                   else
                   x1g=app.left_sites{gmg};
                   x2g=app.right_sites{gmg};
                   x2g=fliplr(x2g);
                   x2g=seqcomp(app,x2g);
                   mg=['GTCTCG' x1g seq x2g 'AGAGAC'];
                   bsaw1=bsaIfind(app,mg);
                   if length(bsaw1{1})>0
                       ogk = 0 ;
                       seq2 = [];
                   end
                   end
                   else
                   x1g=app.left_sites{gmg};
                   x2g=app.right_sites{gmg};
                   x2g=fliplr(x2g);
                   x2g=seqcomp(app,x2g);
                   mg=['GTCTCG' x1g seq x2g 'AGAGAC'];
                   bsaw1=bsaIfind(app,mg);
                   if length(bsaw1{1})>0
                       ogk = 0 ;
                       seq2 = [];
                   end
                end
              seq1=seq2;
                                                                resulg =ogk ;
            else
                seq1=[];
                resulg = 0;
            end
        end
        function [resulg, seq1] = mgok(app,seq,placement)
                ogk = 0;
                seqg = [];
                if length(seq)>22
                  seq = upper(seq)  ;
                   wgng = length(seq) ;
                   seq =[seq seq(1:5) ] ;
                   bsawwg1 = strfind(seq,app.bsaI{2});
                   bsawwg2 = strfind(seq,app.bsaI{1});
                   x1g=app.left_sites{placement};
                   x2g=app.right_sites{placement};
                   y1g=fliplr(x2g);
                   y1g=seqcomp(app,y1g);
                   y2g=fliplr(x1g);
                   y2g=seqcomp(app,y2g);
                   if (length(bsawwg1)==1)&&(length(bsawwg2)==1)
                       seq = seq(1:end-5);
                       y1 = bsawwg1-5;
                       y2 = bsawwg1 + 5;
                       if y2>wgng
                           y2 = y2-wgng;
                       end
                       if y1<1
                           y1 = y1+wgng;
                       end
                       x1 = bsawwg2;
                       x2 = bsawwg2 + 10;
                       if x2>wgng
                           x2 = x2-wgng;
                       end
                       if ((x1<x2)&&(y1<y2)&&((x2<y1)||(y2<x1)))||((((x1>x2)&&(y1<y2))||((x1<x2)&&(y1>y2)))&&(x2<y1)&&(y2<x1))
                       if x1>y2
                           wgg = y2+round((x1-y2-1)/2) ;
                           seq =[seq(wgg+1:end) seq(1:wgg)];
                           x1 =x1-wgg;
                           y2=wgng+y2-wgg;
                           if y1>wgg y1=y1-wgg;
                           else
                           y1=wgng+y1-wgg;
                           end
                           if x2>wgg x2=x2-wgg;
                           else
                           x2=wgng+x2-wgg;
                           end
                       end
                       if (strcmp(seq(x1+7:x1+10),x1g )&&strcmp(seq(y1:y1+3),y1g))||(strcmp(seq(x1+7:x1+10),x2g )&&strcmp(seq(y1:y1+3),y2g))
                       ogk=1;
                       if (strcmp(seq(x1+7:x1+10),x2g )&&strcmp(seq(y1:y1+3),y2g))
                           seq=fliplr(seq);
                           seq=seqcomp(app,seq);
%                            left_sites={'ATCC' 'TATG' 'GAAC' 'CGCA' 'CAGG' 'AGCA'};
%                            right_sites={'CATA' 'GTTC' 'TGCG' 'CCTG' 'TGCT' 'ATGG'};
                       end
                           seqg=seq;
                           if y1-x2<2
                               ogk=0;
                               seqg=[];
                           end
                       end
                       end
                   end
                end
                seq1 =  seqg;
                                                                resulg =ogk ;
         end
        function [resulg,seq1] = cpok(app,seq)
            if seqok(app,seq)
                ogk = 0;
                seqg = [];
                if length(seq)>50
                   seq = upper(seq)  ;
                   wgng = length(seq) ;
                   seq2 = fliplr(seq);
                   seq2 = seqcomp( app,seq2 ) ;
                   seq =[seq seq(1:24)] ;
                   seq2 =[seq2 seq2(1:24)] ;
                   wwg1 = app.left_puc(1:25);
                   wgg1 = app.right_puc(1:25) ;
                   wgg1 = fliplr(wgg1) ;
                   wgg1 = seqcomp(app,wgg1) ;
                   rgg1 =  strfind(seq,wgg1 ) ;
                   lgg1 =  strfind(seq,wwg1 ) ;
                   rgg2 =  strfind(seq2,wgg1 ) ;
                   lgg2 =  strfind(seq2,wwg1 ) ;
                   if ((length(rgg1)==1)&&(length(lgg1)==1)&&(length(rgg2)==0)&&(length(lgg2)==0))||((length(rgg1)==0)&&(length(lgg1)==0)&&(length(rgg2)==1)&&(length(lgg2)==1))
                       ogk = 1 ;
                       if length(rgg1)==1
                           if (rgg1+25-lgg1>=wgng)||((rgg1+25-lgg1<=25)&&(rgg1+25-lgg1>=0))
                               ogk =0 ;
                           else
                               seq = seq(1:end-24) ;
                               a = lgg1;
                               b=rgg1+24;
                               if b>wgng
                                   b=b-wgng;
                               end
                               if a>b
                               seq=[seq(b+round((a-b-1)/2)+1:end) seq(1:b+round((a-b-1)/2)) ];  
                               end
                               seqg = seq ;
                           end
                       else
                       if (rgg2+25-lgg2>=wgng)||((rgg2+25-lgg2<=25)&&(rgg2+25-lgg2>=0))
                               ogk =0 ;
                               else
                               seq2 = seq2(1:end-24) ;
                               a = lgg2;
                               b=rgg2+24;
                               if b>wgng
                                   b=b-wgng;
                               end
                               if a>b
                               seq2=[seq2(b+round((a-b-1)/2)+1:end) seq2(1:b+round((a-b-1)/2)) ];  
                               end
                               seqg = seq2 ;
                       end
                       end
                   end
                end
                                                                resulg =ogk ;
                                                                seq1 = seqg       ;
            else
                resulg = 0;
                seq1 = [];
            end
        end
        
         function [resulg, seq1] = gapok(app,seq)
            if seqok(app,seq)
                ogk = 0;
                seqg = [];
                if length(seq)>22
                  seq = upper(seq)  ;
                   wgng = length(seq) ;
                   seq =[seq seq(1:5) ] ;
                   bsawwg1 = strfind(seq,app.bsaI{2});
                   bsawwg2 = strfind(seq,app.bsaI{1});
                   if (length(bsawwg1)==1)&&(length(bsawwg2)==1)
                       seq = seq(1:end-5);
                       x1 = bsawwg1-5;
                       x2 = bsawwg1 + 5;
                       if x2>wgng
                           x2 = x2-wgng;
                       end
                       if x1<1
                           x1 = x1+wgng;
                       end
                       y1 = bsawwg2;
                       y2 = bsawwg2 + 10;
                       if y2>wgng
                           y2 = y2-wgng;
                       end
                       if ((x1<x2)&&(y1<y2)&&((x2<y1)||(y2<x1)))||((((x1>x2)&&(y1<y2))||((x1<x2)&&(y1>y2)))&&(x2<y1)&&(y2<x1))
                       if x1>y2
                           wgg = y2+round((x1-y2-1)/2) ;
                           seq =[seq(wgg+1:end) seq(1:wgg)];
                           x1 =x1-wgg;
                           y2=wgng+y2-wgg;
                           if y1>wgg y1=y1-wgg;
                           else
                           y1=wgng+y1-wgg;
                           end
                           if x2>wgg x2=x2-wgg;
                           else
                           x2=wgng+x2-wgg;
                           end
                       end
                       if (strcmp(seq(x1:x1+3),'ATCC' )&&strcmp(seq(y1+7:y1+10),'CCAT'))||(strcmp(seq(x1:x1+3),'ATGG' )&&strcmp(seq(y1+7:y1+10),'GGAT'))
                       ogk=1;
                       if (strcmp(seq(x1:x1+3),'ATGG' )&&strcmp(seq(y1+7:y1+10),'GGAT'))
                           seq=fliplr(seq);
                           seq=seqcomp(app,seq);
                       end
                           seqg=seq;
                           if y1-x2<2
ogk=0;
 seqg=[];
                           end
                       end
                       end
                   end
                end
                seq1 =  seqg;
                                                                resulg =ogk ;
            else
                seq1 = [];
                resulg = 0;
            end
         end
         function [resul,gg] = nameok(app,seq)
             if ~isempty(seq)
                 if contains(seq,' ')||contains(seq,'@')||contains(seq,'.')
                     gg=[] ;
                     resul = 0;
                 else
                     gg=seq ;
                     resul = 1;
                 end
                     
             else
                 gg=[];
                 resul=0 ;
             end
         end
         function seq1=pickseq(app,donesname,doneseq,name)
             for i=1:length(donesname)
                 if strcmp(donesname{i},name)
                     seq1=doneseq{i};
                     break
                 end
             end
         end
         function [seq1, mfeatureb] = assemblageseqb(app,dones,i)
             m={'' '' '' '' '' ''};
             for j=1:6
                 m{j}=pickseq(app,dones.modulename{j},dones.moduleseq{j},dones.assemblagename{i,j+1});
                 bsawg1=strfind(m{j},app.bsaI{1});
                 bsawg2=strfind(m{j},app.bsaI{2});
                 if length(bsawg1)==1
                     m{j}=m{j}(bsawg1+11:bsawg2-6);
                 end
             end
             seq=[m{1} app.left_sites{2} m{2} app.left_sites{3} m{3} app.left_sites{4} m{4} app.left_sites{5} m{5} app.left_sites{6} m{6}];
         seq11wg=pickseq(app,dones.apname,dones.apseq,dones.assemblagename{i,1});
         bsawwg1 = strfind(seq11wg,app.bsaI{2});
         bsawwg2 = strfind(seq11wg,app.bsaI{1});
         x1 = bsawwg1-2;
         x2 = bsawwg2 + 7;
         seq1=[seq11wg(1:x1) seq seq11wg(x2:end)];
         wfb={[dones.assemblagename{i,1} ' -backbone'] x1+length(seq)+5 x1-4};
         for j=1:6
         wfb=[wfb;{['Module ' num2str(j) '- ' dones.assemblagename{i,j+1}] wfb{j,3}+5 wfb{j,3}+4+length(m{j})}];
         end
         mfeatureb=wfb;
         end
        
function w = sequencgb(app,mfeatureb,wdb,seq)
%UNTITLED3 Summary of this function goes here
%   Detailed explanation goes here
nseq=length(seq);
if strcmp(wdb,'linear')
    seq1='LOCUS       Exported                  ';
    seq1=[seq1 num2str(nseq)];
    seq1=[seq1  'bp ds-DNA     linear   UNA\r\n'];
    seq1=[seq1 'DEFINITION  natural linear DNA\r\nACCESSION   .\r\nVERSION     .\r\nKEYWORDS    .\r\nSOURCE      natural DNA sequence\r\n  ORGANISM  unspecified\r\nREFERENCE\r\n  AUTHORS   .\r\n  TITLE     Direct Submission\r\n  JOURNAL   Exported\r\nFEATURES             Location/Qualifiers\r\n     source          1..'];
    seq1=[seq1 num2str(nseq) '\r\n'];
    seq1=[seq1 '                     /organism="unspecified"\r\n                     /mol_type="genomic DNA"\r\n'];
else
    seq1='LOCUS       Exported                  ';
    seq1=[seq1 num2str(nseq)];
    seq1=[seq1  'bp ds-DNA     circular SYN\r\n'];
    seq1=[seq1 'DEFINITION  synthetic circular DNA\r\nACCESSION   .\r\nVERSION     .\r\nKEYWORDS    .\r\nSOURCE      synthetic DNA construct\r\n  ORGANISM  synthetic DNA construct\r\nREFERENCE\r\n  AUTHORS   .\r\n  TITLE     Direct Submission\r\n  JOURNAL   Exported\r\nFEATURES             Location/Qualifiers\r\n     source          1..'];
    seq1=[seq1 num2str(nseq) '\r\n'];
    seq1=[seq1 '                     /organism="synthetic DNA construct"\r\n                     /mol_type="other DNA"\r\n'];
end
nlignew=ceil(nseq/60);
if size(mfeatureb,1)>0
for i=1:size(mfeatureb,1)
seq1=[seq1 '     misc_feature    ' num2str(mfeatureb{i,2}) '..' num2str(mfeatureb{i,3}) '\r\n'];    
seq1=[seq1 '                     /note="' mfeatureb{i,1} '"\r\n']; 
end    
end
seq1=[seq1 'ORIGIN\r\n        1 '];
i=0;
while(i<nseq)
i=i+1;
seq1=[seq1 seq(i)];
if (mod(i,60)==0)&&(i<nseq)
seq1=[seq1 '\r\n'];
if 9-length(num2str(i+1))>0
for wj=1:9-length(num2str(i+1))
    seq1=[seq1 ' '];
end
end
seq1=[seq1 num2str(i+1) ' '];
else
if (mod(i,10)==0)&&(i<nseq)
seq1=[seq1 ' '];
end
end
end
seq1=[seq1 '\r\n' '//'];
w=seq1;
        end
        
    end

    methods (Access = public)
    
    function sitset(app,wqm)
            value = app.TextArea.Value;
            m_homol = [value{:}];
            m_homol = upper(m_homol);
            m_homol = strrep(m_homol,' ','');
            m = length(m_homol(m_homol=='A'));
            m = m+length(m_homol(m_homol=='T'));
            m = m+length(m_homol(m_homol=='C'));
            m = m+length(m_homol(m_homol=='G'));
            m_pos = app.ModulepositionSpinner.Value;
            if  1>length(m_homol)||m<length(m_homol)
                qwe= sprintf('A/a T/t G/g C/c code required for the module sequence !\nMinimum 1 nt !');
                           uialert(app.Sequencedit,    qwe, 'Wrong code');
                        return
            end
            [okg sequence]=modulok(app,m_homol,m_pos);
            if okg
            if contains(sequence,'GGTCTC')
                   bsa1=strfind(sequence,'GGTCTC');
                   bsa2=strfind(sequence,'GAGACC');
                   sequence=sequence(bsa1+11:bsa2-6);
            end
            else
               rqw = app.right_sites{m_pos};
               rqw = fliplr(rqw);
               rqw = seqcomp(app,rqw);
               uialert(app.Sequencedit,[    'The module sequence is incorrect ! Check that it is either linear with no bsaI sites or circular (plasmid) containing 2 bsaI sites as well as ' app.left_sites{m_pos} ' and ' rqw ' linker sequences !'], 'Wrong sequence');
       return
            end
             wwg1 = app.left_puc(1:25);
             wgg1 = app.right_puc(1:25) ;
             wgg1 = fliplr(wgg1) ;
             wgg1 = seqcomp(app,wgg1) ;
             rgg1 =  strfind(app.donnes.cpseq{wqm},wgg1 ) ;
             lgg1 =  strfind(app.donnes.cpseq{wqm},wwg1 ) ;
             if (length(rgg1)~=1)||(length(lgg1)~=1)
                 uialert(app.Sequencedit,'The carrying vector sequence is incorrect ! ','Wrong carrying vector');
                 return
             end
             lw=lgg1+24;
             ww1=[app.right_puc(26:end) app.right_sites{m_pos}];
             ww1=fliplr(ww1);
             ww1=seqcomp(app,ww1);
             app.TextArea.Value=[app.donnes.cpseq{wqm}(1:lw) app.left_puc(26:end) app.left_sites{m_pos} sequence ww1 app.donnes.cpseq{wqm}(rgg1:end)];
          uialert(app.Sequencedit, 'The module sequence has been inserted into a carrying plasmid !', 'Success', 'Icon' , 'success');
         end
        
        function resebutton(app)
            if length(app.ListBox.Items)>0
            app.InsertintoacarryingplasmidButton.Enable='on';
            end
            app.inseropenegg=0;
%    app.InsertintoacarryingplasmidButton.Enable='on';
        end
        
                         function wb(app,w1,wf)
                         app.donnes.assemblagename=[app.donnes.assemblagename ; wf]; 
                         app.ListBox.Items=[app.ListBox.Items w1];
                         if isempty(app.ListBox.Value)
                         app.ListBox.Value=app.ListBox.Items{1};    
                         end
                         if length(app.ListBox.Items)==1
                         [app.TextArea.Value,app.featureb]=assemblageseqb(app,app.donnes,1);
                         end
                         app.ExportsequenceButton.Enable='on' ; 
                         end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, db, names, path)
            app.mainapbg=main;
            app.donnesfirst=names;
           switch db
           case 'Modules'
           app.ModulepositionSpinner.Visible='on' ;
               app.ModulepositionLabel.Visible='on' ;
               app.ExtractmodulesequenceonlyButton.Visible='on' ;
               app.InsertintoacarryingplasmidButton.Visible='on' ;
               app.TextArea.Editable='on';
               app.ValidatechangesButton.Visible='on' ;
               app.UndochangesButton.Visible='on' ;
               app.Panel2.Title='Module name';
               app.Panel.Title='Module sequence';
               app.donnesnames=names.modulename{1};
               app.donneseq=names.moduleseq{1};
               app.donnes=names;
               app.ModulepositionSpinner.Value=1;
               if length(app.donnesnames)>0
                   app.ListBox.Items=app.donnesnames;
                   app.ListBox.Value=app.ListBox.Items{1};
                   app.TextArea.Value=app.donneseq{1};
%                    if contains(app.donneseq{1},'GGTCTC')
%                    app.ExtractmodulesequenceonlyButton.Enable='on' ;
%                    else
%             app.ExtractmodulesequenceonlyButton.Enable='off' ;
%                    end
               else
               app.ListBox.Items={};
               app.TextArea.Value='';
               app.ValidatechangesButton.Enable='off' ;
               app.UndochangesButton.Enable='off' ;
               app.ExportsequenceButton.Enable='off' ;
               app.InsertintoacarryingplasmidButton.Enable='off' ;
               app.TextArea.Editable='off';
               app.ExtractmodulesequenceonlyButton.Enable='off' ;
               end
           case 'Destination vectors'
               app.ModulepositionSpinner.Visible='off' ;
               app.ModulepositionLabel.Visible='off' ;
               app.TextArea.Editable='on';
               app.ExtractmodulesequenceonlyButton.Visible='off' ;
               app.InsertintoacarryingplasmidButton.Visible='off' ;
               app.ValidatechangesButton.Visible='on' ;
               app.UndochangesButton.Visible='on' ;
               app.Panel2.Title='Destination vector name';
               app.Panel.Title='Destination vector sequence';
               app.donnesnames=names.apname;
               app.donneseq=names.apseq;
               app.donnes=names;
               if length(app.donnesnames)>0
                   app.ListBox.Items=app.donnesnames;
                   app.ListBox.Value=app.ListBox.Items{1};
                   app.TextArea.Value=app.donneseq{1};
               else
               app.ListBox.Items={};
               app.TextArea.Value='';
               app.ValidatechangesButton.Enable='off' ;
               app.UndochangesButton.Enable='off' ;
               app.ExportsequenceButton.Enable='off' ;
               app.TextArea.Editable='off';
               end
           case 'Carrying vectors'
               app.ModulepositionSpinner.Visible='off' ;
               app.ModulepositionLabel.Visible='off' ;
               app.TextArea.Editable='on';
               app.ExtractmodulesequenceonlyButton.Visible='off' ;
               app.InsertintoacarryingplasmidButton.Visible='off' ;
               app.ValidatechangesButton.Visible='on' ;
               app.UndochangesButton.Visible='on' ;
               app.Panel2.Title='Carrying vector name';
               app.Panel.Title='Carrying vector sequence';
               app.donnesnames=names.cpname;
               app.donneseq=names.cpseq;
               app.donnes=names;
               if length(app.donnesnames)>0
                   app.ListBox.Items=app.donnesnames;
                   app.ListBox.Value=app.ListBox.Items{1};
                   app.TextArea.Value=app.donneseq{1};
               else
               app.ListBox.Items={};
               app.TextArea.Value='';
               app.ValidatechangesButton.Enable='off' ;
               app.UndochangesButton.Enable='off' ;
               app.ExportsequenceButton.Enable='off' ;
               app.TextArea.Editable='off';
               end
           case 'Assembled vectors'
               app.ModulepositionSpinner.Visible='off' ;
               app.ModulepositionLabel.Visible='off' ;
               app.TextArea.Editable='off';
               app.ExtractmodulesequenceonlyButton.Visible='off' ;
               app.InsertintoacarryingplasmidButton.Visible='off' ;
               app.ValidatechangesButton.Visible='off' ;
               app.UndochangesButton.Visible='off' ;
               app.Panel2.Title='Assembled vector name';
               app.Panel.Title='Assembled vector sequence';
               app.donnes=names;
               if ~isempty(app.donnes.assemblagename)
                   assemblagenamed = {} ;
                   for i=1:size(app.donnes.assemblagename,1)
                   assemblagenamed = [assemblagenamed [app.donnes.assemblagename{i,1} '_' app.donnes.assemblagename{i,2} '_' app.donnes.assemblagename{i,3} '_' app.donnes.assemblagename{i,4} '_' app.donnes.assemblagename{i,5} '_' app.donnes.assemblagename{i,6} '_' app.donnes.assemblagename{i,7}]] ;
                   end
                  app.ListBox.Items=assemblagenamed;
                  app.ListBox.Value=app.ListBox.Items{1};
                   [app.TextArea.Value,app.featureb]=assemblageseqb(app,app.donnes,1);
               else
               app.ListBox.Items={};
               app.TextArea.Value='';
               app.ExportsequenceButton.Enable='off' ;
               end
           end
           app.dpathg=path;
           app.dbg=db;
        end

        % Close request function: Sequencedit
        function SequenceditCloseRequest(app, event)
            editdg(app.mainapbg);
            delete(app.inserw);
            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
                 delete(app.inserw);
                 editdg(app.mainapbg);
                 delete(app);
        end

        % Button pushed function: ValidatechangesButton
        function ValidatechangesButtonPushed(app, event)
            namecall=0;
            if length(app.ListBox.Items)>0
                for i=1:length(app.ListBox.Items)
                    if strcmp(app.ListBox.Items{i},app.ListBox.Value)
                        namecall=i;
                    end
                end
            else
               return
            end
            moduleplasmi=0;
            value = app.TextArea.Value;
            m_homol = [value{:}];
            m_homol = upper(m_homol);
            m_homol = strrep(m_homol,' ','');
            m = length(m_homol(m_homol=='A'));
            m = m+length(m_homol(m_homol=='T'));
            m = m+length(m_homol(m_homol=='C'));
            m = m+length(m_homol(m_homol=='G'));
            m_pos = app.ModulepositionSpinner.Value;
            if  1>length(m_homol)||m<length(m_homol)
                qwe= sprintf('A/a T/t G/g C/c code required !\nMinimum 1 nt !');
                           uialert(app.Sequencedit,    qwe, 'Wrong code');
                        return
            end
            switch app.dbg
                case 'Modules'
           [okg sequence]=modulok(app,m_homol,m_pos);
           if okg==0
               rqw = app.right_sites{m_pos};
               rqw = fliplr(rqw);
                rqw = seqcomp(app,rqw);
               uialert(app.Sequencedit,[    'The module sequence is incorrect ! Check that it is either linear with no bsaI sites or circular (plasmid) containing 2 bsaI sites as well as ' app.left_sites{m_pos} ' and ' rqw ' linker sequences !'], 'Wrong sequence');
           return
           end
%            if contains(sequence,'GGTCTC')
%                    app.ExtractmodulesequenceonlyButton.Enable='on' ;
%                    else
%                    app.ExtractmodulesequenceonlyButton.Enable='off' ;
%                   end
           type=['module' num2str(m_pos)];
           seqcall=sequence;
           app.donneseq{namecall}=seqcall;
           app.donnes.moduleseq{m_pos}{namecall}=seqcall;
           case 'Carrying vectors'
[okg sequence]=cpok(app,m_homol);
           if okg==0
               rqw = app.right_puc;
               rqw = fliplr(rqw);
                rqw = seqcomp(app,rqw);
               uialert(app.Sequencedit,[    'The carrying vector sequence is incorrect ! Check that it contains the PUC57 homologous sequences: [' app.left_puc '] and [' rqw '] correctly positioned in order to integrate a module between them !'], 'Wrong sequence');
           return
           end
           seq_data_base = sequence;
           type='cp';
           seqcall=seq_data_base;
           app.donneseq{namecall}=seqcall;
           app.donnes.cpseq{namecall}=seqcall;
                case 'Destination vectors'
                    [okg sequence]=gapok(app,m_homol);
           if okg==0
                rqw = app.right_sites{6};
               rqw = fliplr(rqw);
                rqw = seqcomp(app,rqw);
               uialert(app.Sequencedit,[    'The destination vector sequence is incorrect ! Check that it contains 2 bsaI sites as well as ' app.left_sites{1} ' and ' rqw ' linker sequences !'], 'Wrong sequence');
           return
           end
           seq_data_base = sequence;
           type='ap';
           seqcall=seq_data_base;
           app.donneseq{namecall}=seqcall;
           app.donnes.apseq{namecall}=seqcall;
            end
            seqbasewg(app.mainapbg,type,namecall,seqcall,app.dpathg);
            uialert(app.Sequencedit, 'Data base updated !', 'Success', 'Icon' , 'success');
        end

        % Button pushed function: InsertintoacarryingplasmidButton
        function InsertintoacarryingplasmidButtonPushed(app, event)
           app.InsertintoacarryingplasmidButton.Enable='off';
           app.inseropenegg=1;
           delete(app.inserw);
           app.inserw=inserplasmid(app,app.donnes.cpname) ;
           inserww(app.mainapbg, app.inserw); 
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            if length(app.ListBox.Items)>0
                for i=1:length(app.ListBox.Items)
                    if strcmp(app.ListBox.Items{i},value)
                        j=i;
                        break
                    end    
                end
            end
            switch app.dbg
            case 'Modules'
            app.TextArea.Value=app.donneseq{j};
%             if contains(app.donneseq{j},'GGTCTC')
%             app.ExtractmodulesequenceonlyButton.Enable='on' ;
%             else
%             app.ExtractmodulesequenceonlyButton.Enable='off' ;
%             end
            case 'Assembled vectors'
            [app.TextArea.Value,app.featureb]=assemblageseqb(app,app.donnes,j);
            otherwise
            app.TextArea.Value=app.donneseq{j};
            end
        end

        % Value changing function: ModulepositionSpinner
        function ModulepositionSpinnerValueChanging2(app, event)
            poswg = event.Value;
            app.donnesnames=app.donnes.modulename{poswg};
               app.donneseq=app.donnes.moduleseq{poswg};
               if length(app.donnesnames)>0
               app.TextArea.Editable='on';
               app.ValidatechangesButton.Enable='on' ;
               app.UndochangesButton.Enable='on' ;
               if app.inseropenegg==0
               app.InsertintoacarryingplasmidButton.Enable='on' ;
               end
               app.ExportsequenceButton.Enable='on' ;
               app.ExtractmodulesequenceonlyButton.Enable='on' ;
                   app.ListBox.Items=app.donnesnames;
                   app.ListBox.Value=app.ListBox.Items{1};
                   app.TextArea.Value=app.donneseq{1};
%                    if contains(app.donneseq{1},'GGTCTC')
%                    app.ExtractmodulesequenceonlyButton.Enable='on' ;
%                    else
%        app.ExtractmodulesequenceonlyButton.Enable='off' ;
%                    end
               else
               app.ValidatechangesButton.Enable='off' ;
               app.ExtractmodulesequenceonlyButton.Enable='off' ;
               app.UndochangesButton.Enable='off' ;
               app.ExportsequenceButton.Enable='off' ;
               app.InsertintoacarryingplasmidButton.Enable='off' ;
               app.ListBox.Items={};
                   app.TextArea.Value='';
                app.TextArea.Editable='off';
                   
               end
        end

        % Button pushed function: UndochangesButton
        function UndochangesButtonPushed(app, event)
         if length(app.ListBox.Items)>0
                for i=1:length(app.ListBox.Items)
                    if strcmp(app.ListBox.Items{i},app.ListBox.Value)
                        j=i;
                        break
                    end    
                end
                if strcmp(app.TextArea.Value,app.donneseq{j})
                uialert(app.Sequencedit, 'The sequence has not be modified yet or the changes have already been validated !', 'Warning', 'Icon' , 'warning');
                else
                app.TextArea.Value=app.donneseq{j}; 
                uialert(app.Sequencedit, 'Sequence restored !', 'Success', 'Icon' , 'success');
                end
         end 
        end

        % Button pushed function: ExtractmodulesequenceonlyButton
        function ExtractmodulesequenceonlyButtonPushed(app, event)
            value = app.TextArea.Value;
            m_homol = [value{:}];
            m_homol = upper(m_homol);
            m_homol = strrep(m_homol,' ','');
            m = length(m_homol(m_homol=='A'));
            m = m+length(m_homol(m_homol=='T'));
            m = m+length(m_homol(m_homol=='C'));
            m = m+length(m_homol(m_homol=='G'));
            m_pos = app.ModulepositionSpinner.Value;
            if  1>length(m_homol)||m<length(m_homol)
                qwe= sprintf('A/a T/t G/g C/c code required !\nMinimum 1 nt !');
                           uialert(app.Sequencedit,    qwe, 'Wrong code');
                        return
            end
            [okg sequence]=modulok(app,m_homol,m_pos);
            if okg
            if contains(sequence,'GGTCTC')
                   bsa1=strfind(sequence,'GGTCTC');
                   bsa2=strfind(sequence,'GAGACC');
                   app.TextArea.Value=sequence(bsa1+11:bsa2-6);
                 uialert(app.Sequencedit, 'Module sequence extracted !', 'Success', 'Icon' , 'success');
            else
                 uialert(app.Sequencedit, 'The module sequence is already extracted !', 'Warning', 'Icon' , 'warning');
            end
            else
               rqw = app.right_sites{m_pos};
               rqw = fliplr(rqw);
               rqw = seqcomp(app,rqw);
               uialert(app.Sequencedit,[    'The module sequence is incorrect ! Check that it is either linear with no bsaI sites or circular (plasmid) containing 2 bsaI sites as well as ' app.left_sites{m_pos} ' and ' rqw ' linker sequences !'], 'Wrong sequence');
            end
        end

        % Button pushed function: ExportsequenceButton
        function ExportsequenceButtonPushed(app, event)
            value = app.TextArea.Value;
            m_homol = [value{:}]; 
            m_homol = upper(m_homol);
            m_homol = strrep(m_homol,' ','');
            m = length(m_homol(m_homol=='A'));
            m = m+length(m_homol(m_homol=='T'));
            m = m+length(m_homol(m_homol=='C'));
            m = m+length(m_homol(m_homol=='G'));
            if  1>length(m_homol)||m<length(m_homol)
                qwe= sprintf('A/a T/t G/g C/c code required !\nMinimum 1 nt !');
                           uialert(app.Sequencedit,    qwe, 'Wrong code');
                        return
            end
            switch app.dbg
                case 'Modules'
m_pos = app.ModulepositionSpinner.Value;
           [okg sequence]=modulok(app,m_homol,m_pos);
           if okg==0
               rqw = app.right_sites{m_pos};
               rqw = fliplr(rqw);
                rqw = seqcomp(app,rqw);
               uialert(app.Sequencedit,[    'The module sequence is incorrect ! Check that it is either linear with no bsaI sites or circular (plasmid) containing 2 bsaI sites as well as ' app.left_sites{m_pos} ' and ' rqw ' linker sequences !'], 'Wrong sequence');
           return
           end
%            if contains(sequence,'GGTCTC')
%                    app.ExtractmodulesequenceonlyButton.Enable='on' ;
%                    else
%                    app.ExtractmodulesequenceonlyButton.Enable='off' ;
%                   end
          bsawg1=strfind(sequence,app.bsaI{1});
                 bsawg2=strfind(sequence,app.bsaI{2});
                 if length(bsawg1)==1
                     app.featureb={['Module ' num2str(m_pos) '- ' app.ListBox.Value] bsawg1+11 bsawg2-6};
                     wfb='circular';
                 else
                     app.featureb={};
                     wfb='linear';
                 end
                case 'Carrying vectors'
[okg sequence]=cpok(app,m_homol);
           if okg==0
               rqw = app.right_puc; 
               rqw = fliplr(rqw);
                rqw = seqcomp(app,rqw);
               uialert(app.Sequencedit,[    'The carrying vector sequence is incorrect ! Check that it contains the PUC57 homologous sequences: [' app.left_puc '] and [' rqw '] correctly positioned in order to integrate a module between them !'], 'Wrong sequence');
           return
           end
           wfb='circular';
           case 'Destination vectors'
            [okg sequence]=gapok(app,m_homol);
           if okg==0
                rqw = app.right_sites{6};
               rqw = fliplr(rqw);
                rqw = seqcomp(app,rqw);
               uialert(app.Sequencedit,[    'The destination vector sequence is incorrect ! Check that it contains 2 bsaI sites as well as ' app.left_sites{1} ' and ' rqw ' linker sequences !'], 'Wrong sequence');
           return
           end
           wfb='circular';
           otherwise
           sequence=m_homol;
           wfb='circular';
            end
            gg=mfilename;
            g=mfilename('fullpath') ;
            wfolg=g(1:end-length(gg)) ;
            if ~isfolder([wfolg 'Saved sequences' ] )
                mkdir([wfolg 'Saved sequences' ]);
            end
            hhw = app.ListBox;
            [file_name, Path_name] = uiputfile({'*.gbk' 'genbank'; '*.txt' 'text'}, 'Save file',[wfolg 'Saved sequences' wfolg(end) ]);
            if ~ishandle(hhw)
            return
            end
                       app.Sequencedit.Visible = 'off';
           app.Sequencedit.Visible = 'on';
            if ischar(file_name)
            Filen = fullfile(Path_name, file_name);
            fileid=fopen(Filen,'w');
            if strcmp(file_name(end-2:end),'txt')
            fprintf(fileid,sequence);
            else
            w1b=sequencgb(app,app.featureb,wfb,sequence);
            fprintf(fileid,w1b);
            end
            fclose(fileid);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Sequencedit = uifigure;
            app.Sequencedit.Name = 'View & edit sequence';
            app.Sequencedit.CloseRequestFcn = createCallbackFcn(app, @SequenceditCloseRequest, true);
            app.Panel = uipanel(app.Sequencedit);
            app.Panel.Title = 'Panel';
            app.ValidatechangesButton = uibutton(app.Panel, 'push');
            app.ValidatechangesButton.ButtonPushedFcn = createCallbackFcn(app, @ValidatechangesButtonPushed, true);
            app.ValidatechangesButton.Text = 'Validate changes ✓';
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close ✗';
            app.TextArea = uitextarea(app.Panel);
            app.UndochangesButton = uibutton(app.Panel, 'push');
            app.UndochangesButton.ButtonPushedFcn = createCallbackFcn(app, @UndochangesButtonPushed, true);
            app.UndochangesButton.Text = 'Undo changes';
            app.ExportsequenceButton = uibutton(app.Panel, 'push');
            app.ExportsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportsequenceButtonPushed, true);
            app.ExportsequenceButton.Text = 'Export sequence';
            app.ExtractmodulesequenceonlyButton = uibutton(app.Panel, 'push');
            app.ExtractmodulesequenceonlyButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractmodulesequenceonlyButtonPushed, true);
            app.ExtractmodulesequenceonlyButton.Text = 'Extract module sequence only';
            app.InsertintoacarryingplasmidButton = uibutton(app.Panel, 'push');
            app.InsertintoacarryingplasmidButton.ButtonPushedFcn = createCallbackFcn(app, @InsertintoacarryingplasmidButtonPushed, true);
            app.InsertintoacarryingplasmidButton.Text = 'Insert into a carrying plasmid';
            app.Panel2 = uipanel(app.Sequencedit);
            app.Panel2.Title = 'Panel2';
            app.ListBox = uilistbox(app.Panel2);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ModulepositionSpinner = uispinner(app.Panel2);
            app.ModulepositionSpinner.ValueChangingFcn = createCallbackFcn(app, @ModulepositionSpinnerValueChanging2, true);
            app.ModulepositionSpinner.Limits = [1 6];
            app.ModulepositionSpinner.Value = 1;
            app.ModulepositionLabel = uilabel(app.Panel2);
            app.ModulepositionLabel.Text = 'Module position';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sequencedata(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Sequencedit)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Sequencedit)
        end
    end
end