classdef inserplasmid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectcarryingvectorUIFigure   matlab.ui.Figure
        SelectButton                   matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
        AvailablecarryingvectorsLabel  matlab.ui.control.Label
    end


    properties (Access = private)
    Property % Description
    mainapp;
    names;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, cpnames)
           app.mainapp=main ;
           app.names=cpnames;
           if length(app.names)>0
               app.ListBox.Items=app.names;
               app.ListBox.Value=app.ListBox.Items{1};
           else
               app.AvailablecarryingvectorsLabel.Text='There are no carrying vectors in the data base !';
           app.SelectButton.Enable='off';
           end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            resebutton(app.mainapp);
            delete(app);
        end

        % Close request function: SelectcarryingvectorUIFigure
        function SelectcarryingvectorUIFigureCloseRequest(app, event)
            resebutton(app.mainapp);
            delete(app);
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
       if length(app.ListBox.Items)>0
           for i=1:length(app.ListBox.Items)
               if strcmp(app.ListBox.Items{i},app.ListBox.Value)
                   j=i;
                   break
               end
           end
       sitset(app.mainapp,j);
       end
            resebutton(app.mainapp);
            delete(app) 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectcarryingvectorUIFigure = uifigure;
            app.SelectcarryingvectorUIFigure.Name = 'Select carrying vector';
            app.SelectcarryingvectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @SelectcarryingvectorUIFigureCloseRequest, true);
            app.SelectButton = uibutton(app.SelectcarryingvectorUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.CancelButton = uibutton(app.SelectcarryingvectorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ListBox = uilistbox(app.SelectcarryingvectorUIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.AvailablecarryingvectorsLabel = uilabel(app.SelectcarryingvectorUIFigure);
            app.AvailablecarryingvectorsLabel.Text = 'Available carrying vectors :';
        end
    end

    methods (Access = public)

        % Construct app
        function app = inserplasmid(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectcarryingvectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectcarryingvectorUIFigure)
        end
    end
end