classdef options_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptionsUIFigure                 matlab.ui.Figure
        OKButton                        matlab.ui.control.Button
        Panel_12                        matlab.ui.container.Panel
        OptimalTmCLabel                 matlab.ui.control.Label
        OptimalTmCEditField             matlab.ui.control.NumericEditField
        Panel_13                        matlab.ui.container.Panel
        TmEditFieldLabel                matlab.ui.control.Label
        TmEditField                     matlab.ui.control.NumericEditField
        Panel_14                        matlab.ui.container.Panel
        SizeEditFieldLabel              matlab.ui.control.Label
        SizeEditField                   matlab.ui.control.NumericEditField
        Panel_15                        matlab.ui.container.Panel
        PrimersselfandcrosscomplementarityEditFieldLabel  matlab.ui.control.Label
        PrimersselfandcrosscomplementarityEditField  matlab.ui.control.NumericEditField
        Panel_16                        matlab.ui.container.Panel
        Primersselfandcross3complementarityEditField_16Label  matlab.ui.control.Label
        Primersselfandcross3complementarityEditField_16  matlab.ui.control.NumericEditField
        Panel_17                        matlab.ui.container.Panel
        TemplateectopiccomplementarityEditFieldLabel  matlab.ui.control.Label
        TemplateectopiccomplementarityEditField  matlab.ui.control.NumericEditField
        Panel_18                        matlab.ui.container.Panel
        Templateectopic3complementarityEditFieldLabel  matlab.ui.control.Label
        Templateectopic3complementarityEditField  matlab.ui.control.NumericEditField
        Panel_19                        matlab.ui.container.Panel
        PolyXEditFieldLabel             matlab.ui.control.Label
        PolyXEditField                  matlab.ui.control.NumericEditField
        Panel_20                        matlab.ui.container.Panel
        GCLabel                         matlab.ui.control.Label
        GCEditField                     matlab.ui.control.NumericEditField
        Panel_21                        matlab.ui.container.Panel
        stabilityEditFieldLabel         matlab.ui.control.Label
        stabilityEditField              matlab.ui.control.NumericEditField
        CancelButton                    matlab.ui.control.Button
        PenaltyWeightsLabel             matlab.ui.control.Label
        Panel_22                        matlab.ui.container.Panel
        GCclampsLabel                   matlab.ui.control.Label
        GCclampsEditField               matlab.ui.control.NumericEditField
        Panel_23                        matlab.ui.container.Panel
        PrimersTmdifferenceEditFieldLabel  matlab.ui.control.Label
        PrimersTmdifferenceEditField    matlab.ui.control.NumericEditField
        Panel_24                        matlab.ui.container.Panel
        RestrictionsitelinkerEditFieldLabel  matlab.ui.control.Label
        RestrictionsitelinkerEditField  matlab.ui.control.EditField
        SingleintegrationLabel          matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
        Mainapp
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1, arg2, arg3)
            app.Mainapp = arg1;
            app.OptimalTmCEditField.Value = arg2(1);
            app.TmEditField.Value = arg2(2);
            app.SizeEditField.Value = arg2(3);
            app.PrimersselfandcrosscomplementarityEditField.Value = arg2(4);
            app.Primersselfandcross3complementarityEditField_16.Value = arg2(5);
            app.TemplateectopiccomplementarityEditField.Value = arg2(6);
            app.Templateectopic3complementarityEditField.Value = arg2(7);
            app.PolyXEditField.Value = arg2(8);
            app.GCEditField.Value = arg2(9);
            app.GCclampsEditField.Value = arg2(10);
            app.stabilityEditField.Value  = arg2(11);
            app.PrimersTmdifferenceEditField.Value  = arg2(12);
            app.RestrictionsitelinkerEditField.Value = arg3;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            r_homol = app.RestrictionsitelinkerEditField.Value;
            r_homol = upper(r_homol);
            r_homol = strrep(r_homol,' ','');              
            r = length(r_homol(r_homol=='A'));
            r = r+length(r_homol(r_homol=='T'));
            r = r+length(r_homol(r_homol=='C'));
            r = r+length(r_homol(r_homol=='G'));
            if  (4>length(r_homol))||(16<length(r_homol))||(r<length(r_homol))
            qwe= sprintf('A/a T/t G/g C/c code required for the restriction site linker !\nMinimum 4 nt !\nMaximum 16 nt !');
            uialert(app.OptionsUIFigure,    qwe, 'Wrong code');
            return
            end
            weg = app.OptimalTmCEditField.Value;
            weg = [weg app.TmEditField.Value];
            weg = [weg app.SizeEditField.Value];
            weg = [weg app.PrimersselfandcrosscomplementarityEditField.Value];
            weg = [weg app.Primersselfandcross3complementarityEditField_16.Value];
            weg = [weg app.TemplateectopiccomplementarityEditField.Value];
            weg = [weg app.Templateectopic3complementarityEditField.Value];
            weg = [weg app.PolyXEditField.Value];
            weg = [weg app.GCEditField.Value];
            weg = [weg app.GCclampsEditField.Value];
            weg = [weg app.stabilityEditField.Value];
            weg = [weg app.PrimersTmdifferenceEditField.Value];
            weighto(app.Mainapp,weg,app.RestrictionsitelinkerEditField.Value);
            buttonoption(app.Mainapp);
            delete(app) 
        end

        % Close request function: OptionsUIFigure
        function OptionsUIFigureCloseRequest(app, event)
            buttonoption(app.Mainapp);
            delete(app); 
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            buttonoption(app.Mainapp);
            delete(app);
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.OptimalTmCEditField.Value = 67;
            app.TmEditField.Value = 1;
            app.SizeEditField.Value = 1;
            app.PrimersselfandcrosscomplementarityEditField.Value = 1;
            app.Primersselfandcross3complementarityEditField_16.Value = 1;
            app.TemplateectopiccomplementarityEditField.Value = 1;
            app.Templateectopic3complementarityEditField.Value = 1;
            app.PolyXEditField.Value = 1;
            app.GCEditField.Value = 1;
            app.GCclampsEditField.Value = 1;
            app.stabilityEditField.Value  = 1;
            app.PrimersTmdifferenceEditField.Value  = 1;
            app.RestrictionsitelinkerEditField.Value = 'GGCGCGCCGGCCGGCC';
            app.RestrictionsitelinkerEditField.BackgroundColor = [1 1 1 ] ;
        end

        % Value changed function: RestrictionsitelinkerEditField
        function RestrictionsitelinkerEditFieldValueChanged(app, event)
            r_homol = app.RestrictionsitelinkerEditField.Value;
            r_homol = upper(r_homol);
            r_homol = strrep(r_homol,' ','');              
            r = length(r_homol(r_homol=='A'));
            r = r+length(r_homol(r_homol=='T'));
            r = r+length(r_homol(r_homol=='C'));
            r = r+length(r_homol(r_homol=='G'));
            if  (4>length(r_homol))||(16<length(r_homol))
                app.RestrictionsitelinkerEditField.BackgroundColor = [1 0.8 0.8] ;
            else
                if r<length(r_homol)
                app.RestrictionsitelinkerEditField.BackgroundColor = [1 0.8 0.8] ;
                else
                app.RestrictionsitelinkerEditField.BackgroundColor = [0.8 1 0.8 ] ;
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptionsUIFigure = uifigure;
            app.OptionsUIFigure.Name = 'Options';
            app.OptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @OptionsUIFigureCloseRequest, true);
            app.OKButton = uibutton(app.OptionsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Panel_12 = uipanel(app.OptionsUIFigure);
            app.OptimalTmCLabel = uilabel(app.Panel_12);
            app.OptimalTmCLabel.Text = 'Optimal Tm (°C)';
            app.OptimalTmCEditField = uieditfield(app.Panel_12, 'numeric');
            app.OptimalTmCEditField.Limits = [50 100];
            app.OptimalTmCEditField.Value = 67;
            app.Panel_13 = uipanel(app.OptionsUIFigure);
            app.TmEditFieldLabel = uilabel(app.Panel_13);
            app.TmEditFieldLabel.Text = 'Tm';
            app.TmEditField = uieditfield(app.Panel_13, 'numeric');
            app.TmEditField.Limits = [0 Inf];
            app.TmEditField.Value = 1;
            app.Panel_14 = uipanel(app.OptionsUIFigure);
            app.SizeEditFieldLabel = uilabel(app.Panel_14);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.Panel_14, 'numeric');
            app.SizeEditField.Limits = [0 Inf];
            app.SizeEditField.Value = 1;
            app.Panel_15 = uipanel(app.OptionsUIFigure);
            app.PrimersselfandcrosscomplementarityEditFieldLabel = uilabel(app.Panel_15);
            app.PrimersselfandcrosscomplementarityEditFieldLabel.Text = 'Primers self and cross complementarity';
            app.PrimersselfandcrosscomplementarityEditField = uieditfield(app.Panel_15, 'numeric');
            app.PrimersselfandcrosscomplementarityEditField.Limits = [0 Inf];
            app.PrimersselfandcrosscomplementarityEditField.Value = 1;
            app.Panel_16 = uipanel(app.OptionsUIFigure);
            app.Primersselfandcross3complementarityEditField_16Label = uilabel(app.Panel_16);
            app.Primersselfandcross3complementarityEditField_16Label.Text = 'Primers self and cross 3'' complementarity';
            app.Primersselfandcross3complementarityEditField_16 = uieditfield(app.Panel_16, 'numeric');
            app.Primersselfandcross3complementarityEditField_16.Limits = [0 Inf];
            app.Primersselfandcross3complementarityEditField_16.Value = 1;
            app.Panel_17 = uipanel(app.OptionsUIFigure);
            app.TemplateectopiccomplementarityEditFieldLabel = uilabel(app.Panel_17);
            app.TemplateectopiccomplementarityEditFieldLabel.Text = 'Template ectopic complementarity';
            app.TemplateectopiccomplementarityEditField = uieditfield(app.Panel_17, 'numeric');
            app.TemplateectopiccomplementarityEditField.Limits = [0 Inf];
            app.TemplateectopiccomplementarityEditField.Value = 1;
            app.Panel_18 = uipanel(app.OptionsUIFigure);
            app.Templateectopic3complementarityEditFieldLabel = uilabel(app.Panel_18);
            app.Templateectopic3complementarityEditFieldLabel.Text = 'Template ectopic 3'' complementarity';
            app.Templateectopic3complementarityEditField = uieditfield(app.Panel_18, 'numeric');
            app.Templateectopic3complementarityEditField.Limits = [0 Inf];
            app.Templateectopic3complementarityEditField.Value = 1;
            app.Panel_19 = uipanel(app.OptionsUIFigure);
            app.PolyXEditFieldLabel = uilabel(app.Panel_19);
            app.PolyXEditFieldLabel.Text = 'Poly-X';
            app.PolyXEditField = uieditfield(app.Panel_19, 'numeric');
            app.PolyXEditField.Limits = [0 Inf];
            app.PolyXEditField.Value = 1;
            app.Panel_20 = uipanel(app.OptionsUIFigure);
            app.GCLabel = uilabel(app.Panel_20);
            app.GCLabel.Text = 'G/C %';
            app.GCEditField = uieditfield(app.Panel_20, 'numeric');
            app.GCEditField.Limits = [0 Inf];
            app.GCEditField.Value = 1;
            app.Panel_21 = uipanel(app.OptionsUIFigure);
            app.stabilityEditFieldLabel = uilabel(app.Panel_21);
            app.stabilityEditFieldLabel.Text = '3'' stability';
            app.stabilityEditField = uieditfield(app.Panel_21, 'numeric');
            app.stabilityEditField.Limits = [0 Inf];
            app.stabilityEditField.Value = 1;
            app.CancelButton = uibutton(app.OptionsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.PenaltyWeightsLabel = uilabel(app.OptionsUIFigure);
            app.PenaltyWeightsLabel.Text = 'Penalty Weights:';
            app.Panel_22 = uipanel(app.OptionsUIFigure);
            app.GCclampsLabel = uilabel(app.Panel_22);
            app.GCclampsLabel.Text = 'G/C clamps';
            app.GCclampsEditField = uieditfield(app.Panel_22, 'numeric');
            app.GCclampsEditField.Limits = [0 Inf];
            app.GCclampsEditField.Value = 1;
            app.Panel_23 = uipanel(app.OptionsUIFigure);
            app.PrimersTmdifferenceEditFieldLabel = uilabel(app.Panel_23);
            app.PrimersTmdifferenceEditFieldLabel.Text = 'Primers Tm difference';
            app.PrimersTmdifferenceEditField = uieditfield(app.Panel_23, 'numeric');
            app.PrimersTmdifferenceEditField.Limits = [0 Inf];
            app.PrimersTmdifferenceEditField.Value = 1;
            app.Panel_24 = uipanel(app.OptionsUIFigure);
            app.RestrictionsitelinkerEditFieldLabel = uilabel(app.Panel_24);
            app.RestrictionsitelinkerEditFieldLabel.Text = 'Restriction site linker';
            app.RestrictionsitelinkerEditField = uieditfield(app.Panel_24, 'text');
            app.RestrictionsitelinkerEditField.ValueChangedFcn = createCallbackFcn(app, @RestrictionsitelinkerEditFieldValueChanged, true);
            app.RestrictionsitelinkerEditField.Value = 'GGCGCGCCGGCCGGCC';
            app.SingleintegrationLabel = uilabel(app.OptionsUIFigure);
            app.SingleintegrationLabel.Text = 'Single integration:';
            app.DefaultButton = uibutton(app.OptionsUIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
        end
    end

    methods (Access = public)

        % Construct app
        function app = options_new(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptionsUIFigure)
        end
    end
end