classdef optionsingle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChooselinkersequenceUIFigure    matlab.ui.Figure
        OKButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        RestrictionsitelinkerEditFieldLabel  matlab.ui.control.Label
        RestrictionsitelinkerEditField  matlab.ui.control.EditField
        SingleintegrationLabel          matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
        mainapp;
        ling;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, linkerg)
           app.mainapp=main ;
           app.ling=linkerg;
           app.RestrictionsitelinkerEditField.Value=app.ling;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            resebutton(app.mainapp);
            delete(app);
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.RestrictionsitelinkerEditField.Value='GGCGCGCCGGCCGGCC';
            app.RestrictionsitelinkerEditField.BackgroundColor = [1 1 1 ] ;
        end

        % Close request function: ChooselinkersequenceUIFigure
        function ChooselinkersequenceUIFigureCloseRequest(app, event)
            resebutton(app.mainapp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            r_homol = app.RestrictionsitelinkerEditField.Value;
            r_homol = upper(r_homol);
            r_homol = strrep(r_homol,' ','');              
            r = length(r_homol(r_homol=='A'));
            r = r+length(r_homol(r_homol=='T'));
            r = r+length(r_homol(r_homol=='C'));
            r = r+length(r_homol(r_homol=='G'));
            if  (4>length(r_homol))||(16<length(r_homol))||(r<length(r_homol))
            qwe= sprintf('A/a T/t G/g C/c code required !\n4 to 16 nt !');
            uialert(app.ChooselinkersequenceUIFigure,    qwe, 'Wrong code');
            return
            end
        sitset(app.mainapp,app.RestrictionsitelinkerEditField.Value);
            resebutton(app.mainapp);
            delete(app) 
        end

        % Value changed function: RestrictionsitelinkerEditField
        function RestrictionsitelinkerEditFieldValueChanged(app, event)
             r_homol = app.RestrictionsitelinkerEditField.Value;
             r_homol = upper(r_homol);
             r_homol = strrep(r_homol,' ','');              
             r = length(r_homol(r_homol=='A'));
             r = r+length(r_homol(r_homol=='T'));
             r = r+length(r_homol(r_homol=='C'));
             r = r+length(r_homol(r_homol=='G'));
             if  (4>length(r_homol))||(16<length(r_homol))
                app.RestrictionsitelinkerEditField.BackgroundColor = [1 0.8 0.8] ;
             else
                if r<length(r_homol)
                app.RestrictionsitelinkerEditField.BackgroundColor = [1 0.8 0.8] ;
                else
                app.RestrictionsitelinkerEditField.BackgroundColor = [0.8 1 0.8 ] ;
                end
             end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChooselinkersequenceUIFigure = uifigure;
            app.ChooselinkersequenceUIFigure.Name = 'Choose linker sequence';
            app.ChooselinkersequenceUIFigure.CloseRequestFcn = createCallbackFcn(app, @ChooselinkersequenceUIFigureCloseRequest, true);
            app.OKButton = uibutton(app.ChooselinkersequenceUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.ChooselinkersequenceUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.ChooselinkersequenceUIFigure);
            app.RestrictionsitelinkerEditFieldLabel = uilabel(app.Panel);
            app.RestrictionsitelinkerEditFieldLabel.Text = 'Restriction site linker';
            app.RestrictionsitelinkerEditField = uieditfield(app.Panel, 'text');
            app.RestrictionsitelinkerEditField.ValueChangedFcn = createCallbackFcn(app, @RestrictionsitelinkerEditFieldValueChanged, true);
            app.SingleintegrationLabel = uilabel(app.ChooselinkersequenceUIFigure);
            app.SingleintegrationLabel.Text = 'Single integration:';
            app.DefaultButton = uibutton(app.ChooselinkersequenceUIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
        end
    end

    methods (Access = public)

        % Construct app
        function app = optionsingle(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChooselinkersequenceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChooselinkersequenceUIFigure)
        end
    end
end