classdef remve < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Sequencedit            matlab.ui.Figure
        ActualnamesPanel       matlab.ui.container.Panel
        ListBox                matlab.ui.control.ListBox
        ModulepositionSpinner  matlab.ui.control.Spinner
        ModulepositionLabel    matlab.ui.control.Label
        RemoveButton           matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
        donnesnames;
        donnes;
        dbg;
        dpathg;
        mainapbg;
        inseropenegg=0;
    end

    methods (Access = private)
    
         function results = bsaIfind(app,seq)
            l=length(seq);
            out1={[],[]};
           if l>5
           for n=[1 2]   
           for i=1:l-5
               k=1;
               while (k<=6)&&(seq(i+k-1)==app.bsaI{n}(k))
                  k=k+1;
               end
               if k==7
                  if n==1
                  out1{1}=[out1{1} i];
                  out1{2}=[out1{2} n];
                  else
                  out1{1}=[out1{1} i];
                  out1{2}=[out1{2} n];
                  end
               end
           end
           end
           end
           results=out1;
        end


         function [resul,gg] = nameok(app,seq)
             if ~isempty(seq)
                 if contains(seq,' ')||contains(seq,'@')||contains(seq,'.')
                     gg=[] ;
                     resul = 0;
                 else
                     gg=seq ;
                     resul = 1;
                 end
                     
             else
                 gg=[];
                 resul=0 ;
             end
         end
         function seq1=pickseq(app,donesname,doneseq,name)
             for i=1:length(donesname)
                 if strcmp(donesname{i},name)
                     seq1=doneseq{i};
                     break
                 end
             end
         end
        
    end

    methods (Access = public)

        
                  function wb1(app,wb)
                  app.ListBox.Items=[app.ListBox.Items wb];
                  if isempty(app.ListBox.Value)
                  app.ListBox.Value=app.ListBox.Items{1};    
                  end
                  app.RemoveButton.Enable='on' ;    
                  end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, db, names, path)
            app.mainapbg=main;
           switch db
           case 'Modules'
           app.ModulepositionSpinner.Visible='on' ;
               app.ModulepositionLabel.Visible='on' ;
               app.ActualnamesPanel.Title='Modules names';
               app.donnesnames=names.modulename{1};
               app.donnes=names;
               app.ModulepositionSpinner.Value=1;
               if length(app.donnesnames)>0
                   app.ListBox.Items=app.donnesnames;
                   app.ListBox.Value=app.ListBox.Items{1};
%                    if contains(app.donneseq{1},'GGTCTC')
%                    app.ExtractmodulesequenceonlyButton.Enable='on' ;
%                    else
%             app.ExtractmodulesequenceonlyButton.Enable='off' ;
%                    end
               else
               app.ListBox.Items={};
               app.RemoveButton.Enable='off' ;
               end
           case 'Destination vectors'
               app.ModulepositionSpinner.Visible='off' ;
               app.ModulepositionLabel.Visible='off' ;
               app.donnesnames=names.apname;
               app.donnes=names;
               app.ActualnamesPanel.Title='Destination vectors names';
               if length(app.donnesnames)>0
                   app.ListBox.Items=app.donnesnames;
                   app.ListBox.Value=app.ListBox.Items{1};
               else
               app.ListBox.Items={};
               app.RemoveButton.Enable='off' ;
               end
           case 'Carrying vectors'
               app.ModulepositionSpinner.Visible='off' ;
               app.ModulepositionLabel.Visible='off' ;
               app.donnesnames=names.cpname;
               app.donnes=names;
               app.ActualnamesPanel.Title='Carrying vectors names';
               if length(app.donnesnames)>0
                   app.ListBox.Items=app.donnesnames;
                   app.ListBox.Value=app.ListBox.Items{1};
               else
               app.ListBox.Items={};
               app.RemoveButton.Enable='off' ;
               end
           otherwise
               app.ModulepositionSpinner.Visible='off' ;
               app.ModulepositionLabel.Visible='off' ;
               app.donnes=names;
               app.ActualnamesPanel.Title='Assembled vectors names';
               if ~isempty(app.donnes.assemblagename)
                   assemblagenamed = {} ;
                   for i=1:size(app.donnes.assemblagename,1)
                   assemblagenamed = [assemblagenamed [app.donnes.assemblagename{i,1} '_' app.donnes.assemblagename{i,2} '_' app.donnes.assemblagename{i,3} '_' app.donnes.assemblagename{i,4} '_' app.donnes.assemblagename{i,5} '_' app.donnes.assemblagename{i,6} '_' app.donnes.assemblagename{i,7}]] ;
                   end
                  app.ListBox.Items=assemblagenamed;
                  app.ListBox.Value=app.ListBox.Items{1};
               else
               app.ListBox.Items={};
               app.RemoveButton.Enable='off' ;
               end
           end
           app.dpathg=path;
           app.dbg=db;
        end

        % Close request function: Sequencedit
        function SequenceditCloseRequest(app, event)
            editdg(app.mainapbg);
            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
                 editdg(app.mainapbg);
                 delete(app);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            namecall=0;
            if length(app.ListBox.Items)>0
                for i=1:length(app.ListBox.Items)
                    if strcmp(app.ListBox.Items{i},app.ListBox.Value)
                        namecall=i;
                    end
                end
            else
               return
            end
            switch app.dbg
               case 'Modules'
                    position=app.ModulepositionSpinner.Value;
                    type=['module' num2str(position)];
                    if isempty(app.donnes.modulename{position})
                    return
                    end
                    w1g=true(1,length(app.donnes.modulename{position}));
                    w1g(namecall)=false;
                    app.donnes.modulename{position}=app.donnes.modulename{position}(w1g);
                    app.donnesnames=app.donnesnames(w1g);
                    app.ListBox.Items=app.donnes.modulename{position};
                    if ~isempty(app.ListBox.Items)
                    app.ListBox.Value=app.ListBox.Items{min(namecall,length(app.ListBox.Items))};
                    else
                    app.RemoveButton.Enable='off';
                    end
                case 'Carrying vectors'
                    type='cp';
                    if isempty(app.donnes.cpname)
                    return
                    end
                    w1g=true(1,length(app.donnes.cpname));
                    w1g(namecall)=false;
                    app.donnes.cpname=app.donnes.cpname(w1g);
                    app.donnesnames=app.donnesnames(w1g);
                    app.ListBox.Items=app.donnesnames;
                    if ~isempty(app.ListBox.Items)
                    app.ListBox.Value=app.ListBox.Items{min(namecall,length(app.ListBox.Items))};
                    else
                    app.RemoveButton.Enable='off';
                    end
                case 'Destination vectors'
                     type='ap';
                     if isempty(app.donnes.apname)
                     return
                     end
                     w1g=true(1,length(app.donnes.apname));
                     w1g(namecall)=false;
                     app.donnes.apname=app.donnes.apname(w1g);
                     app.donnesnames=app.donnesnames(w1g);
                     app.ListBox.Items=app.donnesnames;
                     if ~isempty(app.ListBox.Items)
                     app.ListBox.Value=app.ListBox.Items{min(namecall,length(app.ListBox.Items))};
                     else
                     app.RemoveButton.Enable='off';
                     end
                otherwise
                     type='assemblag';
                     if isempty(app.ListBox.Items)
                     return
                     end
                     w1g=true(1,length(app.ListBox.Items));
                     w1g(namecall)=false;
                     app.ListBox.Items=app.ListBox.Items(w1g);
                     if ~isempty(app.ListBox.Items)
                     app.ListBox.Value=app.ListBox.Items{min(namecall,length(app.ListBox.Items))};
                     else
                     app.RemoveButton.Enable='off';
                     end
            end
            removebasewg(app.mainapbg,type,namecall,app.dpathg);
            %uialert(app.Sequencedit, 'Data base updated !', 'Success', 'Icon' , 'success');
        end

        % Value changing function: ModulepositionSpinner
        function ModulepositionSpinnerValueChanging2(app, event)
            poswg = event.Value;
            app.donnesnames=app.donnes.modulename{poswg};
               if length(app.donnesnames)>0
               app.RemoveButton.Enable='on' ;
                  app.ListBox.Items=app.donnesnames;
                  app.ListBox.Value=app.ListBox.Items{1};
%                    if contains(app.donneseq{1},'GGTCTC')
%                    app.ExtractmodulesequenceonlyButton.Enable='on' ;
%                    else
%        app.ExtractmodulesequenceonlyButton.Enable='off' ;
%                    end
              else
               app.RemoveButton.Enable='off' ;
               app.ListBox.Items={};
               end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Sequencedit = uifigure;
            app.Sequencedit.Name = 'Remove from data base';
            app.Sequencedit.CloseRequestFcn = createCallbackFcn(app, @SequenceditCloseRequest, true);
            app.ActualnamesPanel = uipanel(app.Sequencedit);
            app.ActualnamesPanel.Title = 'Actual names :';
            app.ListBox = uilistbox(app.ActualnamesPanel);
            app.ModulepositionSpinner = uispinner(app.ActualnamesPanel);
            app.ModulepositionSpinner.ValueChangingFcn = createCallbackFcn(app, @ModulepositionSpinnerValueChanging2, true);
            app.ModulepositionSpinner.Limits = [1 6];
            app.ModulepositionSpinner.Value = 1;
            app.ModulepositionLabel = uilabel(app.ActualnamesPanel);
            app.ModulepositionLabel.Text = 'Module position';
            app.RemoveButton = uibutton(app.Sequencedit, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.CloseButton = uibutton(app.Sequencedit, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close ✗';
        end
    end

    methods (Access = public)

        % Construct app
        function app = remve(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Sequencedit)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Sequencedit)
        end
    end
end