classdef renam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Sequencedit            matlab.ui.Figure
        EnternewnamePanel      matlab.ui.container.Panel
        EditField              matlab.ui.control.EditField
        ActualnamesPanel       matlab.ui.container.Panel
        ListBox                matlab.ui.control.ListBox
        ModulepositionSpinner  matlab.ui.control.Spinner
        ModulepositionLabel    matlab.ui.control.Label
        ValidatenewnameButton  matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
        donnesnames;
        donnes;
        dbg;
        dpathg;
        mainapbg;
        inseropenegg=0;
    end

    methods (Access = private)
    
         function results = bsaIfind(app,seq)
            l=length(seq);
            out1={[],[]};
           if l>5
           for n=[1 2]   
           for i=1:l-5
               k=1;
               while (k<=6)&&(seq(i+k-1)==app.bsaI{n}(k))
                  k=k+1;
               end
               if k==7
                  if n==1
                  out1{1}=[out1{1} i];
                  out1{2}=[out1{2} n];
                  else
                  out1{1}=[out1{1} i];
                  out1{2}=[out1{2} n];
                  end
               end
           end
           end
           end
           results=out1;
        end


         function [resul,gg] = nameok(app,seq)
             if ~isempty(seq)
                 if contains(seq,' ')||contains(seq,'@')||contains(seq,'.')
                     gg=[] ;
                     resul = 0;
                 else
                     gg=seq ;
                     resul = 1;
                 end
                     
             else
                 gg=[];
                 resul=0 ;
             end
         end
         function seq1=pickseq(app,donesname,doneseq,name)
             for i=1:length(donesname)
                 if strcmp(donesname{i},name)
                     seq1=doneseq{i};
                     break
                 end
             end
         end
        
    end

    methods (Access = public)

        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, db, names, path)
            app.mainapbg=main;
           switch db
           case 'Modules'
           app.ModulepositionSpinner.Visible='on' ;
               app.ModulepositionLabel.Visible='on' ;
               app.donnesnames=names.modulename{1};
               app.donnes=names;
               app.ModulepositionSpinner.Value=1;
               if length(app.donnesnames)>0
                   app.ListBox.Items=app.donnesnames;
                   app.ListBox.Value=app.ListBox.Items{1};
%                    if contains(app.donneseq{1},'GGTCTC')
%                    app.ExtractmodulesequenceonlyButton.Enable='on' ;
%                    else
%             app.ExtractmodulesequenceonlyButton.Enable='off' ;
%                    end
               else
               app.ListBox.Items={};
               app.ValidatenewnameButton.Enable='off' ;
               app.EditField.Editable='off';
               end
           case 'Destination vectors'
               app.ModulepositionSpinner.Visible='off' ;
               app.ModulepositionLabel.Visible='off' ;
               app.donnesnames=names.apname;
               app.donnes=names;
               if length(app.donnesnames)>0
                   app.ListBox.Items=app.donnesnames;
                   app.ListBox.Value=app.ListBox.Items{1};
               else
               app.ListBox.Items={};
               app.ValidatenewnameButton.Enable='off' ;
               app.EditField.Editable='off';
               end
           case 'Carrying vectors'
               app.ModulepositionSpinner.Visible='off' ;
               app.ModulepositionLabel.Visible='off' ;
               app.donnesnames=names.cpname;
               app.donnes=names;
               if length(app.donnesnames)>0
                   app.ListBox.Items=app.donnesnames;
                   app.ListBox.Value=app.ListBox.Items{1};
               else
               app.ListBox.Items={};
               app.ValidatenewnameButton.Enable='off' ;
               app.EditField.Editable='off';
               end
           end
           app.dpathg=path;
           app.dbg=db;
        end

        % Close request function: Sequencedit
        function SequenceditCloseRequest(app, event)
            editdg(app.mainapbg);
            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
                 editdg(app.mainapbg);
                 delete(app);
        end

        % Button pushed function: ValidatenewnameButton
        function ValidatenewnameButtonPushed(app, event)
            namecall=0;
            if length(app.ListBox.Items)>0
                for i=1:length(app.ListBox.Items)
                    if strcmp(app.ListBox.Items{i},app.ListBox.Value)
                        namecall=i;
                    end
                end
            else
               return
            end
            value1 = app.EditField.Value;
        moduleplasmi=0;
            while (~isempty(value1))&&(value1(end)==' ')
                value1=value1(1:end-1);
            end
            if  isempty(value1)||contains(value1,' ')||contains(value1,'@')||contains(value1,'.')
                app.EditField.BackgroundColor = [1 0.8 0.8] ;
                                                        uialert(app.Sequencedit,    'Name should contain at least one character (white spaces, points and "@" character are forbiden) !', 'Wrong name');
                        return
            end
            switch app.dbg
               case 'Modules'
                    position=app.ModulepositionSpinner.Value;
                    type=['module' num2str(position)];
                    if ~isempty(app.donnes.modulename{position})
                        for i=1:length(app.donnes.modulename{position})
                            if strcmp(app.donnes.modulename{position}{i},value1)
                                uialert(app.Sequencedit,    ['There is already a module ' num2str(position) ' named ' value1 ' !'], 'Wrong name') ;
                                return
                            end
                        end
                    else
                    return
                    end
                    app.donnes.modulename{position}{namecall}=value1;
                    app.donnesnames{namecall}=value1;
                    app.ListBox.Items{namecall}=value1;
                    app.ListBox.Value=value1;
                case 'Carrying vectors'
                    type='cp';
                    if ~isempty(app.donnes.cpname)
                        for i=1:length(app.donnes.cpname)
                            if strcmp(app.donnes.cpname{i},value1)
                                uialert(app.Sequencedit,    ['There is already a carrying vector named ' value1 ' !'], 'Wrong name') ;
                                return
                            end
                        end
                    else
                    return
                    end
                    app.donnes.cpname{namecall}=value1;
                    app.donnesnames{namecall}=value1;
                    app.ListBox.Items{namecall}=value1;
                    app.ListBox.Value=value1;
                case 'Destination vectors'
                     type='ap';
                     if ~isempty(app.donnes.apname)
                        for i=1:length(app.donnes.apname)
                            if strcmp(app.donnes.apname{i},value1)
                                uialert(app.Sequencedit,    ['There is already a destination vector named ' value1 ' !'], 'Wrong name') ;
                                return
                            end
                        end
                     else
                     return
                     end
                     app.donnes.apname{namecall}=value1;
                     app.donnesnames{namecall}=value1;
                     app.ListBox.Items{namecall}=value1;
                     app.ListBox.Value=value1;
            end
            namebasewg(app.mainapbg,type,namecall,value1,app.dpathg);
            app.EditField.Value='';
            app.EditField.BackgroundColor = [1 1 1] ;
            uialert(app.Sequencedit, 'Data base updated !', 'Success', 'Icon' , 'success');
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.EditField.Value='';
            app.EditField.BackgroundColor = [1 1 1] ;
        end

        % Value changing function: ModulepositionSpinner
        function ModulepositionSpinnerValueChanging2(app, event)
            app.EditField.Value='';
            app.EditField.BackgroundColor = [1 1 1] ;
            poswg = event.Value;
            app.donnesnames=app.donnes.modulename{poswg};
               if length(app.donnesnames)>0
               app.EditField.Editable='on';
               app.ValidatenewnameButton.Enable='on' ;
                  app.ListBox.Items=app.donnesnames;
                  app.ListBox.Value=app.ListBox.Items{1};
%                    if contains(app.donneseq{1},'GGTCTC')
%                    app.ExtractmodulesequenceonlyButton.Enable='on' ;
%                    else
%        app.ExtractmodulesequenceonlyButton.Enable='off' ;
%                    end
              else
                  app.EditField.Editable='off';
               app.ValidatenewnameButton.Enable='off' ;
               app.ListBox.Items={};
               end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            while (~isempty(value))&&(value(end)==' ')
                value=value(1:end-1);
            end
            if  isempty(value)
                app.EditField.BackgroundColor = [1 0.8 0.8] ;
            else
                if contains(value,' ')||contains(value,'@')||contains(value,'.')
                app.EditField.BackgroundColor = [1 0.8 0.8] ;
                else
                app.EditField.BackgroundColor = [0.8 1 0.8 ] ;
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Sequencedit = uifigure;
            app.Sequencedit.Name = 'Modify names in data base';
            app.Sequencedit.CloseRequestFcn = createCallbackFcn(app, @SequenceditCloseRequest, true);
            app.EnternewnamePanel = uipanel(app.Sequencedit);
            app.EnternewnamePanel.Title = 'Enter new name :';
            app.EditField = uieditfield(app.EnternewnamePanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ActualnamesPanel = uipanel(app.Sequencedit);
            app.ActualnamesPanel.Title = 'Actual names :';
            app.ListBox = uilistbox(app.ActualnamesPanel);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ModulepositionSpinner = uispinner(app.ActualnamesPanel);
            app.ModulepositionSpinner.ValueChangingFcn = createCallbackFcn(app, @ModulepositionSpinnerValueChanging2, true);
            app.ModulepositionSpinner.Limits = [1 6];
            app.ModulepositionSpinner.Value = 1;
            app.ModulepositionLabel = uilabel(app.ActualnamesPanel);
            app.ModulepositionLabel.Text = 'Module position';
            app.ValidatenewnameButton = uibutton(app.Sequencedit, 'push');
            app.ValidatenewnameButton.ButtonPushedFcn = createCallbackFcn(app, @ValidatenewnameButtonPushed, true);
            app.ValidatenewnameButton.Text = 'Validate new name ✓';
            app.CloseButton = uibutton(app.Sequencedit, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close ✗';
        end
    end

    methods (Access = public)

        % Construct app
        function app = renam(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Sequencedit)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Sequencedit)
        end
    end
end