classdef showg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Sequencedit                    matlab.ui.Figure
        TextArea                       matlab.ui.control.TextArea
        CloseButton                    matlab.ui.control.Button
        ExportsequenceButton           matlab.ui.control.Button
        AssembledvectorEditFieldLabel  matlab.ui.control.Label
        AssembledvectorEditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        Property % Description
        left_sites={'ATCC' 'TATG' 'GAAC' 'CGCA' 'CAGG' 'AGCA'};
        right_sites={'CATA' 'GTTC' 'TGCG' 'CCTG' 'TGCT' 'ATGG'};
        left_puc='CGAATGCATCTAGATATCGGATCCCGGTCTCG';
        right_puc='GCAGGCCTCTGCAGTCGACGGGCCCGGTCTCT';
        bsaI={'GGTCTC' 'GAGACC'};
        donnesnames;
        donneseq;
        donnes;
        dbg;
        dpathg;
        mainapbg;
        donnesfirst;
        inserw;
        inseropenegg=0;
        featureb
    end

    methods (Access = private)
    
         function results = bsaIfind(app,seq)
            l=length(seq);
            out1={[],[]};
           if l>5
           for n=[1 2]   
           for i=1:l-5
               k=1;
               while (k<=6)&&(seq(i+k-1)==app.bsaI{n}(k))
                  k=k+1;
               end
               if k==7
                  if n==1
                  out1{1}=[out1{1} i];
                  out1{2}=[out1{2} n];
                  else
                  out1{1}=[out1{1} i];
                  out1{2}=[out1{2} n];
                  end
               end
           end
           end
           end
           results=out1;
        end


         function seq1=pickseq(app,donesname,doneseq,name)
             for i=1:length(donesname)
                 if strcmp(donesname{i},name)
                     seq1=doneseq{i};
                     break
                 end
             end
         end
         function [seq1,mfeatureb] = assemblageseqb(app,dones,i)
             m={'' '' '' '' '' ''};
             for j=1:6
                 m{j}=pickseq(app,dones.modulename{j},dones.moduleseq{j},i{j+1});
                 bsawg1=strfind(m{j},app.bsaI{1});
                 bsawg2=strfind(m{j},app.bsaI{2});
                 if length(bsawg1)==1
                     m{j}=m{j}(bsawg1+11:bsawg2-6);
                 end
             end
             seq=[m{1} app.left_sites{2} m{2} app.left_sites{3} m{3} app.left_sites{4} m{4} app.left_sites{5} m{5} app.left_sites{6} m{6}];
         seq11wg=pickseq(app,dones.apname,dones.apseq,i{1});
         bsawwg1 = strfind(seq11wg,app.bsaI{2});
         bsawwg2 = strfind(seq11wg,app.bsaI{1});
         x1 = bsawwg1-2; 
         x2 = bsawwg2 + 7;
         seq1=[seq11wg(1:x1) seq seq11wg(x2:end)];
         wfb={[i{1} ' -backbone'] x1+length(seq)+5 x1-4};
         for j=1:6
         wfb=[wfb;{['Module ' num2str(j) '- ' i{j+1}] wfb{j,3}+5 wfb{j,3}+4+length(m{j})}];
         end
         mfeatureb=wfb;
         end
        
function w = sequencgb(app,mfeatureb,wdb,seq)
%UNTITLED3 Summary of this function goes here
%   Detailed explanation goes here
nseq=length(seq);
if strcmp(wdb,'linear')
    seq1='LOCUS       Exported                  ';
    seq1=[seq1 num2str(nseq)];
    seq1=[seq1  'bp ds-DNA     linear   UNA\r\n'];
    seq1=[seq1 'DEFINITION  natural linear DNA\r\nACCESSION   .\r\nVERSION     .\r\nKEYWORDS    .\r\nSOURCE      natural DNA sequence\r\n  ORGANISM  unspecified\r\nREFERENCE\r\n  AUTHORS   .\r\n  TITLE     Direct Submission\r\n  JOURNAL   Exported\r\nFEATURES             Location/Qualifiers\r\n     source          1..'];
    seq1=[seq1 num2str(nseq) '\r\n'];
    seq1=[seq1 '                     /organism="unspecified"\r\n                     /mol_type="genomic DNA"\r\n'];
else
    seq1='LOCUS       Exported                  ';
    seq1=[seq1 num2str(nseq)];
    seq1=[seq1  'bp ds-DNA     circular SYN\r\n'];
    seq1=[seq1 'DEFINITION  synthetic circular DNA\r\nACCESSION   .\r\nVERSION     .\r\nKEYWORDS    .\r\nSOURCE      synthetic DNA construct\r\n  ORGANISM  synthetic DNA construct\r\nREFERENCE\r\n  AUTHORS   .\r\n  TITLE     Direct Submission\r\n  JOURNAL   Exported\r\nFEATURES             Location/Qualifiers\r\n     source          1..'];
    seq1=[seq1 num2str(nseq) '\r\n'];
    seq1=[seq1 '                     /organism="synthetic DNA construct"\r\n                     /mol_type="other DNA"\r\n'];
end
nlignew=ceil(nseq/60);
if size(mfeatureb,1)>0
for i=1:size(mfeatureb,1)
seq1=[seq1 '     misc_feature    ' num2str(mfeatureb{i,2}) '..' num2str(mfeatureb{i,3}) '\r\n'];    
seq1=[seq1 '                     /note="' mfeatureb{i,1} '"\r\n']; 
end    
end
seq1=[seq1 'ORIGIN\r\n        1 '];
i=0;
while(i<nseq)
i=i+1;
seq1=[seq1 seq(i)];
if (mod(i,60)==0)&&(i<nseq)
seq1=[seq1 '\r\n'];
if 9-length(num2str(i+1))>0
for wj=1:9-length(num2str(i+1))
    seq1=[seq1 ' '];
end
end
seq1=[seq1 num2str(i+1) ' '];
else
if (mod(i,10)==0)&&(i<nseq)
seq1=[seq1 ' '];
end
end
end
seq1=[seq1 '\r\n' '//'];
w=seq1;
      end
        
    end

    methods (Access = public)


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, ww1, names)
            app.mainapbg=main;
            app.donnes=names; 
            [app.TextArea.Value,app.featureb]=assemblageseqb(app,app.donnes,ww1);
                   assemblagenamed = [ww1{1} '_' ww1{2} '_' ww1{3} '_' ww1{4} '_' ww1{5} '_' ww1{6} '_' ww1{7}] ;
                   app.AssembledvectorEditField.Value=assemblagenamed;
        end

        % Close request function: Sequencedit
        function SequenceditCloseRequest(app, event)
            editdg(app.mainapbg);
            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
                 editdg(app.mainapbg);
                 delete(app);
        end

        % Button pushed function: ExportsequenceButton
        function ExportsequenceButtonPushed(app, event)
            value = app.TextArea.Value;
            m_homol = [value{:}];
            m_homol = upper(m_homol);
            sequence=m_homol;
            gg=mfilename;
            g=mfilename('fullpath') ;
            wfolg=g(1:end-length(gg)) ;
            if ~isfolder([wfolg 'Saved sequences' ] )
                mkdir([wfolg 'Saved sequences' ]);
            end
            hhw = app.AssembledvectorEditField;
            [file_name, Path_name] = uiputfile({'*.gbk' 'genbank'; '*.txt' 'text'}, 'Save file',[wfolg 'Saved sequences' wfolg(end) ]);
            if ~ishandle(hhw)
            return
            end
                       app.Sequencedit.Visible = 'off';
           app.Sequencedit.Visible = 'on';
            if ischar(file_name)
            Filen = fullfile(Path_name, file_name);
            fileid=fopen(Filen,'w');
            if strcmp(file_name(end-2:end),'txt')
            fprintf(fileid,sequence);
            else
            w1b=sequencgb(app,app.featureb,'circular',sequence);
            fprintf(fileid,w1b);
            end
            fclose(fileid);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Sequencedit = uifigure;
            app.Sequencedit.Name = 'View sequence';
            app.Sequencedit.CloseRequestFcn = createCallbackFcn(app, @SequenceditCloseRequest, true);
            app.TextArea = uitextarea(app.Sequencedit);
            app.TextArea.Editable = 'off';
            app.CloseButton = uibutton(app.Sequencedit, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close ✗';
            app.ExportsequenceButton = uibutton(app.Sequencedit, 'push');
            app.ExportsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportsequenceButtonPushed, true);
            app.ExportsequenceButton.Text = 'Export sequence';
            app.AssembledvectorEditFieldLabel = uilabel(app.Sequencedit);
            app.AssembledvectorEditFieldLabel.Text = 'Assembled vector :';
            app.AssembledvectorEditField = uieditfield(app.Sequencedit, 'text');
            app.AssembledvectorEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = showg(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Sequencedit)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Sequencedit)
        end
    end
end